classdef stage2Reviewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        LoadReportButton               matlab.ui.control.Button
        DataSetLabel                   matlab.ui.control.Label
        PreviousNeuronButton           matlab.ui.control.Button
        NextNeuronButton               matlab.ui.control.Button
        TotalNeuronsInitialLabel       matlab.ui.control.Label
        NeurNumLabel                   matlab.ui.control.Label
        ShankLabel                     matlab.ui.control.Label
        ShankNeuronNumberLabel         matlab.ui.control.Label
        RasterButton                   matlab.ui.control.Button
        CrossCorrelationsButton        matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        VoltageRangeuVEditFieldLabel   matlab.ui.control.Label
        VoltageRangeuVEditField        matlab.ui.control.NumericEditField
        TimeRangeLabel                 matlab.ui.control.Label
        TimeRangemsEditFieldLabel      matlab.ui.control.Label
        TimeRangemsEditField           matlab.ui.control.NumericEditField
        NeuronInformationLabel         matlab.ui.control.Label
        NumberofSpikesLabel            matlab.ui.control.Label
        FiringRatehzLabel              matlab.ui.control.Label
        RefractoryViolatorsLabel       matlab.ui.control.Label
        NumberofWaveformsEditFieldLabel  matlab.ui.control.Label
        NumberofWaveformsEditField     matlab.ui.control.NumericEditField
        GoToNeuronEditFieldLabel       matlab.ui.control.Label
        GoToNeuronEditField            matlab.ui.control.NumericEditField
        PrevViewedNeuronButton         matlab.ui.control.Button
        DynFiringRateButton            matlab.ui.control.Button
        xMinumEditFieldLabel           matlab.ui.control.Label
        xMinumEditField                matlab.ui.control.NumericEditField
        ShankWaveformPlotParametersLabel  matlab.ui.control.Label
        xMinumEditFieldLabel_2         matlab.ui.control.Label
        xMinumEditField_2              matlab.ui.control.NumericEditField
        xMinumEditFieldLabel_3         matlab.ui.control.Label
        xMinumEditField_3              matlab.ui.control.NumericEditField
        xMinumEditFieldLabel_4         matlab.ui.control.Label
        xMinumEditField_4              matlab.ui.control.NumericEditField
        GeneratePlotsLabel             matlab.ui.control.Label
        NeuronStatusButton             matlab.ui.control.Button
        NeuronRulingButtonGroup        matlab.ui.container.ButtonGroup
        AcceptButton                   matlab.ui.control.RadioButton
        RejectButton                   matlab.ui.control.RadioButton
        FlagButton                     matlab.ui.control.RadioButton
        SaveReportButton               matlab.ui.control.Button
        OutputFileLabel                matlab.ui.control.Label
        MergeIntoNeuronEditFieldLabel  matlab.ui.control.Label
        MergeIntoNeuronEditField       matlab.ui.control.NumericEditField
        NotMergedLabel                 matlab.ui.control.Label
        FingerprintButton              matlab.ui.control.Button
        CompareWaveformsButton         matlab.ui.control.Button
        EditField                      matlab.ui.control.NumericEditField
        EditField_2                    matlab.ui.control.NumericEditField
        vsLabel                        matlab.ui.control.Label
        NeuronsListBoxLabel            matlab.ui.control.Label
        NeuronsListBox                 matlab.ui.control.ListBox
        SimilarNeuronsListBoxLabel     matlab.ui.control.Label
        SimilarNeuronsListBox          matlab.ui.control.ListBox
        TotalNeuronsFinalLabel         matlab.ui.control.Label
        MergeSuggestionLabel           matlab.ui.control.Label
        RefViolPercentageLabel         matlab.ui.control.Label
    end

    
    properties (Access = public)
        fileName % data set file name
        pathName % path to the loaded data set
        iNeur % neuron currently being displayed
        waveformWindowSize % the sizes of the waveform plots on the shank
        vBounds % the voltage bounds for the waveforms
        shankGeo % the geometry information for the channels on each shank
        shankWaveforms % the waveforms for the neurons at each channel on a shank
        neurFR % the neuron firing rate information
        neurRefVio % the number of refractory period violators for each neuron
        neurNSpk % the number of spikes for each neuron
        neurAC % the autocorrelations for each neuron
        neurSTA % the spike triggered average pairing for each neuron
        neurList % list of shank and shank neuron numbers for all neurons
        neurDynFR % the dynamic firing rate througout the recording
        raster % example raster of spikes from all neurons
        neurStage1Ruling % channel rulings from stage 1
        iNeurPrev % previously viewed neuron
        neurRuling % ruling on whether to keep or reject neurons
        mergeOrder % instructions to merge certain neurons together
        shankAveWaveforms % average waveforms
        shankDistWaveforms % distribution shape related values for noise around average waveforms
        shankWaveformDifs % differences in average waveforms between neurons
        mergeGuide % merge suggestions from kilosort
    end
    

    methods (Access = private)

        % Button pushed function: LoadReportButton
        function LoadReportButtonPushed(app, event)
            [file,path,~] = uigetfile;
            if ~isequal(file,0)
                
                % Load the pre-review file
                if strcmp(file(7:9),'Pre') % If a pre-review file was selected, load it
                    load([path,filesep,file],'waveformWindowSize','vBounds','shankGeo','shankWaveforms','neurFR','neurRefVio','neurNSpk','neurAC','neurSTA','neurList','neurDynFR','raster','neurStage1Ruling','shankAveWaveforms','shankDistWaveforms','shankWaveformDifs','mergeGuide')
                elseif strcmp(file(7:10),'Post') % If a post-review file was selected, load its pre-review file
                    try
                        load([path,filesep,[file(1:6),'Pre',file(11:end)]],'waveformWindowSize','vBounds','shankGeo','shankWaveforms','neurFR','neurRefVio','neurNSpk','neurAC','neurSTA','neurList','neurDynFR','raster','neurStage1Ruling','shankAveWaveforms','shankDistWaveforms','shankWaveformDifs','mergeGuide')
                    catch
                        error('The appropriate pre-review file must be in this same directory.')
                    end
                else
                    error('File does not have proper name for a stage 2 review file.')
                end
                
                % Store relevant data
                if strcmp(file(7:9),'Pre')
                    app.fileName = file;
                elseif strcmp(file(7:10),'Post')
                    app.fileName = [file(1:6),'Pre',file(11:end)];
                end
                app.pathName = path;
                app.waveformWindowSize = waveformWindowSize;
                app.vBounds = vBounds;
                app.shankGeo = shankGeo;
                app.shankWaveforms = shankWaveforms;
                app.neurFR = neurFR;
                app.neurRefVio = neurRefVio;
                app.neurNSpk = neurNSpk;
                app.neurAC = neurAC;
                app.neurSTA = neurSTA;
                app.neurList = neurList;
                app.neurDynFR = neurDynFR;
                app.raster = raster;
                app.neurStage1Ruling = neurStage1Ruling;
                app.shankAveWaveforms = shankAveWaveforms;
                app.shankDistWaveforms = shankDistWaveforms;
                app.shankWaveformDifs = shankWaveformDifs;
                app.mergeGuide = mergeGuide;
                
                % Update the data set label
                app.DataSetLabel.Text = ['Data Set: ',file];
                
                % Update the neuron index
                app.iNeur = 1;
                app.iNeurPrev = 1;
                iShank = app.neurList(app.iNeur,1);
                iShankNeur = app.neurList(app.iNeur,2);
                
                % Create the neuron ruling variable (0: not reviewed, 1: accepted, 2: rejected, 3: flagged)
                app.neurRuling = zeros([length(app.neurList),1]);
                if strcmp(file(7:10),'Post') % If this is a post-review file, update the neurRuling variable
                    load([path,filesep,file],'neurRuling')
                    app.neurRuling = neurRuling;
                end
                
                % Create the merge order list
                app.mergeOrder = (1:length(app.neurList))';
                if strcmp(file(7:10),'Post') % If this is a post-review file, update the neurRuling variable
                    load([path,filesep,file],'mergeOrder')
                    app.mergeOrder = mergeOrder;
                end
                
                % Update the total number of neurons initial label
                app.TotalNeuronsInitialLabel.Text = ['Total Neurons (Initial): ',num2str(length(app.neurList))];
                
                % Update the total number of neurons final label
                nTotNeurons = nnz(app.neurRuling == 1) - nnz(app.mergeOrder ~= (1:length(app.neurList))');
                app.TotalNeuronsFinalLabel.Text = ['Total Neurons (Final): ',num2str(nTotNeurons)];
                
                % Update the neuron counter label
                app.NeurNumLabel.Text = num2str(app.iNeur);
                
                % Update the merge order
                app.MergeIntoNeuronEditField.Value = app.mergeOrder(app.iNeur);
                
                % Update the merged label
                if app.mergeOrder(app.iNeur) ~= app.iNeur
                    app.NotMergedLabel.Text = 'Merged';
                    app.NotMergedLabel.FontColor = 'r';
                else
                    app.NotMergedLabel.Text = 'Not Merged';
                    app.NotMergedLabel.FontColor = 'k';
                end
                
                % Update merge suggestion
                if isempty(app.mergeGuide{iShank}{iShankNeur})
                    app.MergeSuggestionLabel.Text = ['Merge Suggestion',newline,'No Merge'];
                    app.MergeSuggestionLabel.FontColor = 'k';
                elseif isequal(app.mergeGuide{iShank}{iShankNeur},0)
                    app.MergeSuggestionLabel.Text = ['Merge Suggestion',newline,'Noise'];
                    app.MergeSuggestionLabel.FontColor = 'b';
                elseif length(app.mergeGuide{iShank}{iShankNeur}) > 1
                    app.MergeSuggestionLabel.Text = ['Merge Suggestion',newline,'Split'];
                    app.MergeSuggestionLabel.FontColor = 'm';
                else
                    mergeTarget = find((app.neurList(:,1) == iShank) & (app.neurList(:,2) == app.mergeGuide{iShank}{iShankNeur}));
                    app.MergeSuggestionLabel.Text = ['Merge Suggestion',newline,num2str(mergeTarget)];
                    app.MergeSuggestionLabel.FontColor = 'r';
                end
                
                % Update the neuron information fields
                app.ShankLabel.Text = ['Shank: ',num2str(iShank)];
                app.ShankNeuronNumberLabel.Text = ['Shank Neuron Number: ',num2str(iShankNeur)];
                app.NumberofSpikesLabel.Text = ['Number of Spikes: ',num2str(app.neurNSpk{iShank}(iShankNeur))];
                app.FiringRatehzLabel.Text = ['Firing Rate (hz): ',num2str(app.neurFR{iShank}(iShankNeur))];
                app.RefractoryViolatorsLabel.Text = ['Refractory Violators: ', num2str(app.neurRefVio{iShank}(iShankNeur))];
                app.RefViolPercentageLabel.Text = ['%Ref.Viol.: ', num2str((app.neurRefVio{iShank}(iShankNeur)*100)/app.neurNSpk{iShank}(iShankNeur),'%.1f') '%'];
                
                
                % Plot the waveforms on the shank
                hold(app.UIAxes,'on')
                zoom(app.UIAxes,'on')
                wfs = app.shankWaveforms{iShank}{iShankNeur};
                awfs = app.shankAveWaveforms{iShank}{iShankNeur};
                nChannels = size(wfs,3);
                nExWaveforms = size(wfs,4);
                xMin = inf;
                xMax = -inf;
                yMin = inf;
                yMax = -inf;
                % v1 = min(app.vBounds{iShank}(:,1));
                % v2 = max(app.vBounds{iShank}(:,2));
                %tempV = app.shankWaveforms{iShank}{iShankNeur}(2,:,:,:);
                %v1 = min(tempV(:));
                %v2 = max(tempV(:));
                v1 = min(awfs(:));
                v2 = max(awfs(:));
                dv = v2 - v1;
                for iChannel = 1:nChannels
                    % Convert time to physical dimensions on shank
                    t1 = wfs(1,1,1,1);
                    t2 = wfs(1,end,1,1);
                    dx1 = app.shankGeo{iShank}(iChannel,1) - app.waveformWindowSize(iShank,1);
                    dx2 = app.shankGeo{iShank}(iChannel,1) + app.waveformWindowSize(iShank,1);
                    mx = (dx2 - dx1)/(t2 - t1);
                    bx = dx1 - (mx*t1);
                    x = mx*wfs(1,:,iChannel,1) + bx;
                    xMin = min([xMin,min(x)]);
                    xMax = max([xMax,max(x)]);
                    
                    % Convert voltage to physical dimensions on the shank
                    dy1 = app.shankGeo{iShank}(iChannel,2) - app.waveformWindowSize(iShank,2);
                    dy2 = app.shankGeo{iShank}(iChannel,2) + app.waveformWindowSize(iShank,2);
                    my = (dy2 - dy1)/dv;
                    by = dy1 - (my*v1);
                    yMin = min([yMin,dy1]);
                    yMax = max([yMax,dy2]);
                    
                    % Plot the channels
                    for iExWaveform = 1:min([app.NumberofWaveformsEditField.Value,size(wfs,4)])
                        if app.neurStage1Ruling{iShank}(iChannel) == 0
                            plot(app.UIAxes,x,my*wfs(2,:,iChannel,iExWaveform) + by,'g','LineWidth',0.2)
                        elseif app.neurStage1Ruling{iShank}(iChannel) == 2
                            plot(app.UIAxes,x,my*wfs(2,:,iChannel,iExWaveform) + by,'r','LineWidth',0.2)
                        end
                    end
                    
                    % Plot the average waveform
                    if app.neurStage1Ruling{iShank}(iChannel) == 0
                        plot(app.UIAxes,x,my*awfs(:,iChannel) + by,'k','LineWidth',1.5)
                    elseif app.neurStage1Ruling{iShank}(iChannel) == 2
                        plot(app.UIAxes,x,my*awfs(:,iChannel) + by,'r','LineWidth',1.5)
                    end
                end
                dx = xMax - xMin;
                dy = yMax - yMin;
                app.UIAxes.XLim = [xMin - 0.1*dx,xMax + 0.1*dx];
                app.UIAxes.YLim = [yMin - 0.1*dy,yMax + 0.1*dy];
                app.xMinumEditField.Value = xMin - 0.1*dx;
                app.xMinumEditField_2.Value = xMax + 0.1*dx;
                app.xMinumEditField_3.Value = yMin - 0.1*dy;
                app.xMinumEditField_4.Value = yMax + 0.1*dy;
                app.VoltageRangeuVEditField.Value = dv; % Update voltage range display
                app.TimeRangeLabel.Text = ['Shank Time Range: ',num2str(t2 - t1),' ms'];
                
                % Draw voltage and time bars in lower left corner for reference
                line(app.UIAxes,[xMin - 0.05*dx,xMin - 0.05*dx],[yMin - 0.05*dy,yMin - 0.05*dy + (dy2 - dy1)],'Color','k','LineWidth',1.5)
                line(app.UIAxes,[xMin - 0.05*dx,xMin - 0.05*dx + (dx2 - dx1)],[yMin - 0.05*dy,yMin - 0.05*dy],'Color','k','LineWidth',1.5)


                % Plot the auto-correlation
                if app.TimeRangemsEditField.Value > app.neurAC{iShank}(1,end)
                    app.TimeRangemsEditField.Value = app.neurAC{iShank}(1,end);
                end
                x = app.neurAC{iShank}(1,:);
                y = app.neurAC{iShank}(iShankNeur + 1,(x >= (-app.TimeRangemsEditField.Value)) & (x <= app.TimeRangemsEditField.Value));
                x = x((x >= (-app.TimeRangemsEditField.Value)) & (x <= app.TimeRangemsEditField.Value));
                bar(app.UIAxes2,x,y,'hist','k')
                app.UIAxes2.XLim = [x(1),x(end)];
                if max(y) == 0
                    app.UIAxes2.YLim = [0,10];
                else
                    app.UIAxes2.YLim = [0,1.1*max(y)];
                end
                
                % Deal with the neuron ruling
                if app.neurRuling(app.iNeur) ~= 0
                    % The neuron ruling has been previously set, so just display the selection.
                    if app.neurRuling(app.iNeur) == 1
                        app.AcceptButton.Value = true;
                        app.RejectButton.Value = false;
                        app.FlagButton.Value = false;
                    elseif app.neurRuling(app.iNeur) == 2
                        app.AcceptButton.Value = false;
                        app.RejectButton.Value = true;
                        app.FlagButton.Value = false;
                    elseif app.neurRuling(app.iNeur) == 3
                        app.AcceptButton.Value = false;
                        app.RejectButton.Value = false;
                        app.FlagButton.Value = true;
                    end
                else
                    % The neuron has not been ruled on, so assume it will be rejected for now.
                    app.neurRuling(app.iNeur) = 2;
                    app.AcceptButton.Value = false;
                    app.RejectButton.Value = true;
                    app.FlagButton.Value = false;
                end
                
                % Update the neuron listing
                nNeurons = size(app.neurList,1);
                tempItems = cell([1,nNeurons]);
                for iNeuron = 1:nNeurons
                    tString = [num2str(iNeuron),' (S: ',num2str(app.neurList(iNeuron,1)),'-',num2str(app.neurList(iNeuron,2)),')'];
                    if app.mergeOrder(iNeuron) ~= iNeuron
                        tString = [tString,' (M into ',num2str(app.mergeOrder(iNeuron)),')'];
                    end
                    if app.neurRuling(iNeuron) == 1 % Accepted
                        tString = [tString,' -Accept-'];
                    elseif app.neurRuling(iNeuron) == 2 % Rejected
                        tString = [tString,' -Reject-'];
                    elseif app.neurRuling(iNeuron) == 3 % Flagged
                        tString = [tString,' -Flag-'];
                    end
                    tempItems{iNeuron} = tString;
                end
                app.NeuronsListBox.Items = tempItems;
                app.NeuronsListBox.Value = tempItems{app.iNeur};
                
                % Update the similar neuron listing
                simList = find(app.neurList(:,1) == app.neurList(app.iNeur,1));
                simList = setdiff(simList,app.iNeur);
                nNeurons = length(simList);
                simScore = sum(app.shankDistWaveforms{app.neurList(app.iNeur,1)}(app.neurList(app.iNeur,2),:,3))*ones([nNeurons,1]);
                for jNeuron = 1:nNeurons
                    simScore(jNeuron) = simScore(jNeuron)/sum(squeeze(app.shankWaveformDifs{app.neurList(app.iNeur,1)}(app.neurList(app.iNeur,2),app.neurList(simList(jNeuron),2),:)));
                end
                [simScore,I] = sort(simScore,'descend');
                simList = simList(I);
                tempItems = cell([1,nNeurons]);
                for iNeuron = 1:nNeurons
                    tString = [num2str(simList(iNeuron)),' (Sim Score: ',num2str(simScore(iNeuron),4),')'];
                    if app.mergeOrder(iNeuron) ~= iNeuron
                        tString = [tString,' (M into ',num2str(app.mergeOrder(simList(iNeuron))),')'];
                    end
                    if app.neurRuling(simList(iNeuron)) == 1 % Accepted
                        tString = [tString,' -Accept-'];
                    elseif app.neurRuling(simList(iNeuron)) == 2 % Rejected
                        tString = [tString,' -Reject-'];
                    elseif app.neurRuling(simList(iNeuron)) == 3 % Flagged
                        tString = [tString,' -Flag-'];
                    end
                    tempItems{iNeuron} = tString;
                end
                app.SimilarNeuronsListBox.Items = tempItems;
                app.SimilarNeuronsListBox.Value = {};
                
                
            end
        end

        % Button pushed function: PreviousNeuronButton
        function PreviousNeuronButtonPushed(app, event)
            if ~isempty(app.shankWaveforms)
                if app.iNeur > 1
                    
                    % Update the neuron index
                    app.iNeurPrev = app.iNeur;
                    app.iNeur = app.iNeur - 1;
                    iShank = app.neurList(app.iNeur,1);
                    iShankNeur = app.neurList(app.iNeur,2);
                    
                    % Update the total number of neurons initial label
                    app.TotalNeuronsInitialLabel.Text = ['Total Neurons (Initial): ',num2str(length(app.neurList))];
                    
                    % Update the total number of neurons final label
                    nTotNeurons = nnz(app.neurRuling == 1) - nnz(app.mergeOrder ~= (1:length(app.neurList))');
                    app.TotalNeuronsFinalLabel.Text = ['Total Neurons (Final): ',num2str(nTotNeurons)];
                    
                    % Update the neuron counter label
                    app.NeurNumLabel.Text = num2str(app.iNeur);
                    
                    % Update the merge order
                    app.MergeIntoNeuronEditField.Value = app.mergeOrder(app.iNeur);
                    
                    % Update the merged label
                    if app.mergeOrder(app.iNeur) ~= app.iNeur
                        app.NotMergedLabel.Text = 'Merged';
                        app.NotMergedLabel.FontColor = 'r';
                    else
                        app.NotMergedLabel.Text = 'Not Merged';
                        app.NotMergedLabel.FontColor = 'k';
                    end
                    
                    % Update merge suggestion
                    if isempty(app.mergeGuide{iShank}{iShankNeur})
                        app.MergeSuggestionLabel.Text = ['Merge Suggestion',newline,'No Merge'];
                        app.MergeSuggestionLabel.FontColor = 'k';
                    elseif isequal(app.mergeGuide{iShank}{iShankNeur},0)
                        app.MergeSuggestionLabel.Text = ['Merge Suggestion',newline,'Noise'];
                        app.MergeSuggestionLabel.FontColor = 'b';
                    elseif length(app.mergeGuide{iShank}{iShankNeur}) > 1
                        app.MergeSuggestionLabel.Text = ['Merge Suggestion',newline,'Split'];
                        app.MergeSuggestionLabel.FontColor = 'm';
                    else
                        mergeTarget = find((app.neurList(:,1) == iShank) & (app.neurList(:,2) == app.mergeGuide{iShank}{iShankNeur}));
                        app.MergeSuggestionLabel.Text = ['Merge Suggestion',newline,num2str(mergeTarget)];
                        app.MergeSuggestionLabel.FontColor = 'r';
                    end
                    
                    % Update the neuron information fields
                    app.ShankLabel.Text = ['Shank: ',num2str(iShank)];
                    app.ShankNeuronNumberLabel.Text = ['Shank Neuron Number: ',num2str(iShankNeur)];
                    app.NumberofSpikesLabel.Text = ['Number of Spikes: ',num2str(app.neurNSpk{iShank}(iShankNeur))];
                    app.FiringRatehzLabel.Text = ['Firing Rate (hz): ',num2str(app.neurFR{iShank}(iShankNeur))];
                    app.RefractoryViolatorsLabel.Text = ['Refractory Violators: ',num2str(app.neurRefVio{iShank}(iShankNeur))];
                    app.RefViolPercentageLabel.Text = ['%Ref.Viol.: ', num2str((app.neurRefVio{iShank}(iShankNeur)*100)/app.neurNSpk{iShank}(iShankNeur),'%.1f') '%'];
                    
                    
                    % Plot the waveforms on the shank
                    cla(app.UIAxes)
                    hold(app.UIAxes,'on')
                    zoom(app.UIAxes,'on')
                    wfs = app.shankWaveforms{iShank}{iShankNeur};
                    awfs = app.shankAveWaveforms{iShank}{iShankNeur};
                    nChannels = size(wfs,3);
                    nExWaveforms = size(wfs,4);
                    xMin = inf;
                    xMax = -inf;
                    yMin = inf;
                    yMax = -inf;
                    %tempV = app.shankWaveforms{iShank}{iShankNeur}(2,:,:,:);
                    %v1 = min(tempV(:));
                    %v2 = max(tempV(:));
                    v1 = min(awfs(:));
                    v2 = max(awfs(:));
                    dv = v2 - v1;
                    for iChannel = 1:nChannels
                        % Convert time to physical dimensions on shank
                        t1 = wfs(1,1,1,1);
                        t2 = wfs(1,end,1,1);
                        dx1 = app.shankGeo{iShank}(iChannel,1) - app.waveformWindowSize(iShank,1);
                        dx2 = app.shankGeo{iShank}(iChannel,1) + app.waveformWindowSize(iShank,1);
                        mx = (dx2 - dx1)/(t2 - t1);
                        bx = dx1 - (mx*t1);
                        x = mx*wfs(1,:,iChannel,1) + bx;
                        xMin = min([xMin,min(x)]);
                        xMax = max([xMax,max(x)]);
                        
                        % Convert voltage to physical dimensions on the shank
                        dy1 = app.shankGeo{iShank}(iChannel,2) - app.waveformWindowSize(iShank,2);
                        dy2 = app.shankGeo{iShank}(iChannel,2) + app.waveformWindowSize(iShank,2);
                        my = (dy2 - dy1)/dv;
                        by = dy1 - (my*v1);
                        yMin = min([yMin,dy1]);
                        yMax = max([yMax,dy2]);
                        
                        % Plot the channels
                        for iExWaveform = 1:min([app.NumberofWaveformsEditField.Value,size(wfs,4)])
                            if app.neurStage1Ruling{iShank}(iChannel) == 0
                                plot(app.UIAxes,x,my*wfs(2,:,iChannel,iExWaveform) + by,'g','LineWidth',0.2)
                            elseif app.neurStage1Ruling{iShank}(iChannel) == 2
                                plot(app.UIAxes,x,my*wfs(2,:,iChannel,iExWaveform) + by,'r','LineWidth',0.2)
                            end
                        end
                        
                        % Plot the average waveform
                        if app.neurStage1Ruling{iShank}(iChannel) == 0
                            plot(app.UIAxes,x,my*awfs(:,iChannel) + by,'k','LineWidth',1.5)
                        elseif app.neurStage1Ruling{iShank}(iChannel) == 2
                            plot(app.UIAxes,x,my*awfs(:,iChannel) + by,'r','LineWidth',1.5)
                        end
                    end
                    dx = xMax - xMin;
                    dy = yMax - yMin;
                    app.UIAxes.XLim = [xMin - 0.1*dx,xMax + 0.1*dx];
                    app.UIAxes.YLim = [yMin - 0.1*dy,yMax + 0.1*dy];
                    app.xMinumEditField.Value = xMin - 0.1*dx;
                    app.xMinumEditField_2.Value = xMax + 0.1*dx;
                    app.xMinumEditField_3.Value = yMin - 0.1*dy;
                    app.xMinumEditField_4.Value = yMax + 0.1*dy;
                    app.VoltageRangeuVEditField.Value = dv; % Update voltage range display
                    app.TimeRangeLabel.Text = ['Shank Time Range: ',num2str(t2 - t1),' ms'];
                    
                    % Draw voltage and time bars in lower left corner for reference
                    line(app.UIAxes,[xMin - 0.05*dx,xMin - 0.05*dx],[yMin - 0.05*dy,yMin - 0.05*dy + (dy2 - dy1)],'Color','k','LineWidth',1.5)
                    line(app.UIAxes,[xMin - 0.05*dx,xMin - 0.05*dx + (dx2 - dx1)],[yMin - 0.05*dy,yMin - 0.05*dy],'Color','k','LineWidth',1.5)


                    % Plot the auto-correlation
                    if app.TimeRangemsEditField.Value > app.neurAC{iShank}(1,end)
                        app.TimeRangemsEditField.Value = app.neurAC{iShank}(1,end);
                    end
                    x = app.neurAC{iShank}(1,:);
                    y = app.neurAC{iShank}(iShankNeur + 1,(x >= (-app.TimeRangemsEditField.Value)) & (x <= app.TimeRangemsEditField.Value));
                    x = x((x >= (-app.TimeRangemsEditField.Value)) & (x <= app.TimeRangemsEditField.Value));
                    bar(app.UIAxes2,x,y,'hist','k')
                    app.UIAxes2.XLim = [x(1),x(end)];
                    if max(y) == 0
                        app.UIAxes2.YLim = [0,10];
                    else
                        app.UIAxes2.YLim = [0,1.1*max(y)];
                    end
                    
                    % Deal with the neuron ruling
                    if app.neurRuling(app.iNeur) ~= 0
                        % The neuron ruling has been previously set, so just display the selection.
                        if app.neurRuling(app.iNeur) == 1
                            app.AcceptButton.Value = true;
                            app.RejectButton.Value = false;
                            app.FlagButton.Value = false;
                        elseif app.neurRuling(app.iNeur) == 2
                            app.AcceptButton.Value = false;
                            app.RejectButton.Value = true;
                            app.FlagButton.Value = false;
                        elseif app.neurRuling(app.iNeur) == 3
                            app.AcceptButton.Value = false;
                            app.RejectButton.Value = false;
                            app.FlagButton.Value = true;
                        end
                    else
                        % The neuron has not been ruled on, so assume it will be accepted for now.
                        app.neurRuling(app.iNeur) = 2;
                        app.AcceptButton.Value = false;
                        app.RejectButton.Value = true;
                        app.FlagButton.Value = false;
                    end
                    
                    % Update the neuron listing
                    nNeurons = size(app.neurList,1);
                    tempItems = cell([1,nNeurons]);
                    for iNeuron = 1:nNeurons
                        tString = [num2str(iNeuron),' (S: ',num2str(app.neurList(iNeuron,1)),'-',num2str(app.neurList(iNeuron,2)),')'];
                        if app.mergeOrder(iNeuron) ~= iNeuron
                            tString = [tString,' (M into ',num2str(app.mergeOrder(iNeuron)),')'];
                        end
                        if app.neurRuling(iNeuron) == 1 % Accepted
                            tString = [tString,' -Accept-'];
                        elseif app.neurRuling(iNeuron) == 2 % Rejected
                            tString = [tString,' -Reject-'];
                        elseif app.neurRuling(iNeuron) == 3 % Flagged
                            tString = [tString,' -Flag-'];
                        end
                        tempItems{iNeuron} = tString;
                    end
                    app.NeuronsListBox.Items = tempItems;
                    app.NeuronsListBox.Value = tempItems{app.iNeur};
                    
                    % Update the similar neuron listing
                    simList = find(app.neurList(:,1) == app.neurList(app.iNeur,1));
                    simList = setdiff(simList,app.iNeur);
                    nNeurons = length(simList);
                    simScore = sum(app.shankDistWaveforms{app.neurList(app.iNeur,1)}(app.neurList(app.iNeur,2),:,3))*ones([nNeurons,1]);
                    for jNeuron = 1:nNeurons
                        simScore(jNeuron) = simScore(jNeuron)/sum(squeeze(app.shankWaveformDifs{app.neurList(app.iNeur,1)}(app.neurList(app.iNeur,2),app.neurList(simList(jNeuron),2),:)));
                    end
                    [simScore,I] = sort(simScore,'descend');
                    simList = simList(I);
                    tempItems = cell([1,nNeurons]);
                    for iNeuron = 1:nNeurons
                        tString = [num2str(simList(iNeuron)),' (Sim Score: ',num2str(simScore(iNeuron),4),')'];
                        if app.mergeOrder(iNeuron) ~= iNeuron
                            tString = [tString,' (M into ',num2str(app.mergeOrder(simList(iNeuron))),')'];
                        end
                        if app.neurRuling(simList(iNeuron)) == 1 % Accepted
                            tString = [tString,' -Accept-'];
                        elseif app.neurRuling(simList(iNeuron)) == 2 % Rejected
                            tString = [tString,' -Reject-'];
                        elseif app.neurRuling(simList(iNeuron)) == 3 % Flagged
                            tString = [tString,' -Flag-'];
                        end
                        tempItems{iNeuron} = tString;
                    end
                    app.SimilarNeuronsListBox.Items = tempItems;
                    app.SimilarNeuronsListBox.Value = {};
                    
                end
            end
        end

        % Button pushed function: NextNeuronButton
        function NextNeuronButtonPushed(app, event)
            if ~isempty(app.shankWaveforms)
                if app.iNeur < size(app.neurList,1)
                    
                    % Update the neuron index
                    app.iNeurPrev = app.iNeur;
                    app.iNeur = app.iNeur + 1;
                    iShank = app.neurList(app.iNeur,1);
                    iShankNeur = app.neurList(app.iNeur,2);
                    
                    % Update the total number of neurons initial label
                    app.TotalNeuronsInitialLabel.Text = ['Total Neurons (Initial): ',num2str(length(app.neurList))];
                    
                    % Update the total number of neurons final label
                    nTotNeurons = nnz(app.neurRuling == 1) - nnz(app.mergeOrder ~= (1:length(app.neurList))');
                    app.TotalNeuronsFinalLabel.Text = ['Total Neurons (Final): ',num2str(nTotNeurons)];
                    
                    % Update the neuron counter label
                    app.NeurNumLabel.Text = num2str(app.iNeur);
                    
                    % Update the merge order
                    app.MergeIntoNeuronEditField.Value = app.mergeOrder(app.iNeur);
                    
                    % Update the merged label
                    if app.mergeOrder(app.iNeur) ~= app.iNeur
                        app.NotMergedLabel.Text = 'Merged';
                        app.NotMergedLabel.FontColor = 'r';
                    else
                        app.NotMergedLabel.Text = 'Not Merged';
                        app.NotMergedLabel.FontColor = 'k';
                    end
                    
                    % Update merge suggestion
                    if isempty(app.mergeGuide{iShank}{iShankNeur})
                        app.MergeSuggestionLabel.Text = ['Merge Suggestion',newline,'No Merge'];
                        app.MergeSuggestionLabel.FontColor = 'k';
                    elseif isequal(app.mergeGuide{iShank}{iShankNeur},0)
                        app.MergeSuggestionLabel.Text = ['Merge Suggestion',newline,'Noise'];
                        app.MergeSuggestionLabel.FontColor = 'b';
                    elseif length(app.mergeGuide{iShank}{iShankNeur}) > 1
                        app.MergeSuggestionLabel.Text = ['Merge Suggestion',newline,'Split'];
                        app.MergeSuggestionLabel.FontColor = 'm';
                    else
                        mergeTarget = find((app.neurList(:,1) == iShank) & (app.neurList(:,2) == app.mergeGuide{iShank}{iShankNeur}));
                        app.MergeSuggestionLabel.Text = ['Merge Suggestion',newline,num2str(mergeTarget)];
                        app.MergeSuggestionLabel.FontColor = 'r';
                    end
                    
                    % Update the neuron information fields
                    app.ShankLabel.Text = ['Shank: ',num2str(iShank)];
                    app.ShankNeuronNumberLabel.Text = ['Shank Neuron Number: ',num2str(iShankNeur)];
                    app.NumberofSpikesLabel.Text = ['Number of Spikes: ',num2str(app.neurNSpk{iShank}(iShankNeur))];
                    app.FiringRatehzLabel.Text = ['Firing Rate (hz): ',num2str(app.neurFR{iShank}(iShankNeur))];
                    app.RefractoryViolatorsLabel.Text = ['Refractory Violators: ',num2str(app.neurRefVio{iShank}(iShankNeur))];
                    app.RefViolPercentageLabel.Text = ['%Ref.Viol.: ', num2str((app.neurRefVio{iShank}(iShankNeur)*100)/app.neurNSpk{iShank}(iShankNeur),'%.1f') '%'];
                    
                    
                    % Plot the waveforms on the shank
                    cla(app.UIAxes)
                    hold(app.UIAxes,'on')
                    zoom(app.UIAxes,'on')
                    wfs = app.shankWaveforms{iShank}{iShankNeur};
                    awfs = app.shankAveWaveforms{iShank}{iShankNeur};
                    nChannels = size(wfs,3);
                    nExWaveforms = size(wfs,4);
                    xMin = inf;
                    xMax = -inf;
                    yMin = inf;
                    yMax = -inf;
                    %tempV = app.shankWaveforms{iShank}{iShankNeur}(2,:,:,:);
                    %v1 = min(tempV(:));
                    %v2 = max(tempV(:));
                    v1 = min(awfs(:));
                    v2 = max(awfs(:));
                    dv = v2 - v1;
                    for iChannel = 1:nChannels
                        % Convert time to physical dimensions on shank
                        t1 = wfs(1,1,1,1);
                        t2 = wfs(1,end,1,1);
                        dx1 = app.shankGeo{iShank}(iChannel,1) - app.waveformWindowSize(iShank,1);
                        dx2 = app.shankGeo{iShank}(iChannel,1) + app.waveformWindowSize(iShank,1);
                        mx = (dx2 - dx1)/(t2 - t1);
                        bx = dx1 - (mx*t1);
                        x = mx*wfs(1,:,iChannel,1) + bx;
                        xMin = min([xMin,min(x)]);
                        xMax = max([xMax,max(x)]);
                        
                        % Convert voltage to physical dimensions on the shank
                        dy1 = app.shankGeo{iShank}(iChannel,2) - app.waveformWindowSize(iShank,2);
                        dy2 = app.shankGeo{iShank}(iChannel,2) + app.waveformWindowSize(iShank,2);
                        my = (dy2 - dy1)/dv;
                        by = dy1 - (my*v1);
                        yMin = min([yMin,dy1]);
                        yMax = max([yMax,dy2]);
                        
                        % Plot the channels
                        for iExWaveform = 1:min([app.NumberofWaveformsEditField.Value,size(wfs,4)])
                            if app.neurStage1Ruling{iShank}(iChannel) == 0
                                plot(app.UIAxes,x,my*wfs(2,:,iChannel,iExWaveform) + by,'g','LineWidth',0.2)
                            elseif app.neurStage1Ruling{iShank}(iChannel) == 2
                                plot(app.UIAxes,x,my*wfs(2,:,iChannel,iExWaveform) + by,'r','LineWidth',0.2)
                            end
                        end
                        
                        % Plot the average waveform
                        if app.neurStage1Ruling{iShank}(iChannel) == 0
                            plot(app.UIAxes,x,my*awfs(:,iChannel) + by,'k','LineWidth',1.5)
                        elseif app.neurStage1Ruling{iShank}(iChannel) == 2
                            plot(app.UIAxes,x,my*awfs(:,iChannel) + by,'r','LineWidth',1.5)
                        end
                    end
                    dx = xMax - xMin;
                    dy = yMax - yMin;
                    app.UIAxes.XLim = [xMin - 0.1*dx,xMax + 0.1*dx];
                    app.UIAxes.YLim = [yMin - 0.1*dy,yMax + 0.1*dy];
                    app.xMinumEditField.Value = xMin - 0.1*dx;
                    app.xMinumEditField_2.Value = xMax + 0.1*dx;
                    app.xMinumEditField_3.Value = yMin - 0.1*dy;
                    app.xMinumEditField_4.Value = yMax + 0.1*dy;
                    app.VoltageRangeuVEditField.Value = dv; % Update voltage range display
                    app.TimeRangeLabel.Text = ['Shank Time Range: ',num2str(t2 - t1),' ms'];
                    
                    % Draw voltage and time bars in lower left corner for reference
                    line(app.UIAxes,[xMin - 0.05*dx,xMin - 0.05*dx],[yMin - 0.05*dy,yMin - 0.05*dy + (dy2 - dy1)],'Color','k','LineWidth',1.5)
                    line(app.UIAxes,[xMin - 0.05*dx,xMin - 0.05*dx + (dx2 - dx1)],[yMin - 0.05*dy,yMin - 0.05*dy],'Color','k','LineWidth',1.5)


                    % Plot the auto-correlation
                    if app.TimeRangemsEditField.Value > app.neurAC{iShank}(1,end)
                        app.TimeRangemsEditField.Value = app.neurAC{iShank}(1,end);
                    end
                    x = app.neurAC{iShank}(1,:);
                    y = app.neurAC{iShank}(iShankNeur + 1,(x >= (-app.TimeRangemsEditField.Value)) & (x <= app.TimeRangemsEditField.Value));
                    x = x((x >= (-app.TimeRangemsEditField.Value)) & (x <= app.TimeRangemsEditField.Value));
                    bar(app.UIAxes2,x,y,'hist','k')
                    app.UIAxes2.XLim = [x(1),x(end)];
                    if max(y) == 0
                        app.UIAxes2.YLim = [0,10];
                    else
                        app.UIAxes2.YLim = [0,1.1*max(y)];
                    end
                    
                    % Deal with the neuron ruling
                    if app.neurRuling(app.iNeur) ~= 0
                        % The neuron ruling has been previously set, so just display the selection.
                        if app.neurRuling(app.iNeur) == 1
                            app.AcceptButton.Value = true;
                            app.RejectButton.Value = false;
                            app.FlagButton.Value = false;
                        elseif app.neurRuling(app.iNeur) == 2
                            app.AcceptButton.Value = false;
                            app.RejectButton.Value = true;
                            app.FlagButton.Value = false;
                        elseif app.neurRuling(app.iNeur) == 3
                            app.AcceptButton.Value = false;
                            app.RejectButton.Value = false;
                            app.FlagButton.Value = true;
                        end
                    else
                        % The neuron has not been ruled on, so assume it will be accepted for now.
                        app.neurRuling(app.iNeur) = 2;
                        app.AcceptButton.Value = false;
                        app.RejectButton.Value = true;
                        app.FlagButton.Value = false;
                    end
                    
                    % Update the neuron listing
                    nNeurons = size(app.neurList,1);
                    tempItems = cell([1,nNeurons]);
                    for iNeuron = 1:nNeurons
                        tString = [num2str(iNeuron),' (S: ',num2str(app.neurList(iNeuron,1)),'-',num2str(app.neurList(iNeuron,2)),')'];
                        if app.mergeOrder(iNeuron) ~= iNeuron
                            tString = [tString,' (M into ',num2str(app.mergeOrder(iNeuron)),')'];
                        end
                        if app.neurRuling(iNeuron) == 1 % Accepted
                            tString = [tString,' -Accept-'];
                        elseif app.neurRuling(iNeuron) == 2 % Rejected
                            tString = [tString,' -Reject-'];
                        elseif app.neurRuling(iNeuron) == 3 % Flagged
                            tString = [tString,' -Flag-'];
                        end
                        tempItems{iNeuron} = tString;
                    end
                    app.NeuronsListBox.Items = tempItems;
                    app.NeuronsListBox.Value = tempItems{app.iNeur};
                    
                    % Update the similar neuron listing
                    simList = find(app.neurList(:,1) == app.neurList(app.iNeur,1));
                    simList = setdiff(simList,app.iNeur);
                    nNeurons = length(simList);
                    simScore = sum(app.shankDistWaveforms{app.neurList(app.iNeur,1)}(app.neurList(app.iNeur,2),:,3))*ones([nNeurons,1]);
                    for jNeuron = 1:nNeurons
                        simScore(jNeuron) = simScore(jNeuron)/sum(squeeze(app.shankWaveformDifs{app.neurList(app.iNeur,1)}(app.neurList(app.iNeur,2),app.neurList(simList(jNeuron),2),:)));
                    end
                    [simScore,I] = sort(simScore,'descend');
                    simList = simList(I);
                    tempItems = cell([1,nNeurons]);
                    for iNeuron = 1:nNeurons
                        tString = [num2str(simList(iNeuron)),' (Sim Score: ',num2str(simScore(iNeuron),4),')'];
                        if app.mergeOrder(iNeuron) ~= iNeuron
                            tString = [tString,' (M into ',num2str(app.mergeOrder(simList(iNeuron))),')'];
                        end
                        if app.neurRuling(simList(iNeuron)) == 1 % Accepted
                            tString = [tString,' -Accept-'];
                        elseif app.neurRuling(simList(iNeuron)) == 2 % Rejected
                            tString = [tString,' -Reject-'];
                        elseif app.neurRuling(simList(iNeuron)) == 3 % Flagged
                            tString = [tString,' -Flag-'];
                        end
                        tempItems{iNeuron} = tString;
                    end
                    app.SimilarNeuronsListBox.Items = tempItems;
                    app.SimilarNeuronsListBox.Value = {};
                    
                end
            end
        end

        % Button pushed function: RasterButton
        function RasterButtonPushed(app, event)
            if ~isempty(app.shankWaveforms)
                
                figure
                hold on
                
                nShanks = length(unique(app.neurList(:,1)));
                nNeurons = size(app.neurList,1);
                
                % Make different colors for the different shanks
                Colors = colormap('jet');
                Colors = Colors(round(linspace(1,size(Colors,1),nShanks + 1)),:);
                
                xMin = inf;
                xMax = -inf;
                
                for iNeuron = 1:nNeurons
                    if app.neurRuling(iNeuron) ~= 2 %do not plot rejected clusters
                        iShank = app.neurList(iNeuron,1);
                        iShankNeur = app.neurList(iNeuron,2);
                        if ~isempty(app.raster{iShank}{iShankNeur})
                            scatter(app.raster{iShank}{iShankNeur},iNeuron*ones(size(app.raster{iShank}{iShankNeur})),5,'filled','MarkerFaceColor',Colors(iShank,:))
                            xMin = min([xMin,app.raster{iShank}{iShankNeur}(1)]);
                            xMax = max([xMax,app.raster{iShank}{iShankNeur}(end)]);
                        end
                    end
                end
                xlim([xMin,xMax])
                ylim([0,nNeurons + 1])
                xlabel('Time (sec)')
                ylabel('Neurons')
                title('Spike Raster (Shanks Color Coded)')


            end
        end

        % Button pushed function: CrossCorrelationsButton
        function CrossCorrelationsButtonPushed(app, event)
            if ~isempty(app.shankWaveforms)
                
                % Get the indexes
                iShank = app.neurList(app.iNeur,1);
                iShankNeur = app.neurList(app.iNeur,2);
                
                % Set the number of figures in the panel
                nHorFig = 3;
                nVertFig = 2;
                nSubFigs = nHorFig*nVertFig;
                
                % Organize the data
                nNeurons = size(app.neurSTA{iShank},2);
                x = squeeze(app.neurSTA{iShank}(1,1,:));
                iSubFig = 1;
                for jNeuron = setdiff(1:nNeurons,iShankNeur)
                    
                    % Find the neuron indexes for the other neuron
                    jShank = iShank;
                    jNeur = find((app.neurList(:,1) == jShank) & (app.neurList(:,2) == jNeuron));
                    
                    if iSubFig == 1
                        figure
                    end
                    
                    if ismember(app.neurRuling(jNeur),[1,3])
                        % Plot if this other neuron is accepted or flagged
                        subplot(nVertFig,nHorFig,iSubFig)
                        hold on
                        
                        y = squeeze(app.neurSTA{iShank}(iShankNeur,jNeuron,:));
                        bar(x,y,'hist','k')
                        
                        xlim([x(1) - 0.5*(x(2) - x(1)),x(end) + 0.5*(x(2) - x(1))])
                        if isequal(y,zeros(size(y)))
                            ylim([0,1])
                        else
                            ylim([0,1.1*max(y)])
                        end
                        xlabel('Time (ms)')
                        ylabel('Number of Spikes')
                        title(['Activity of ',num2str(jNeur),' (S:',num2str(jShank),'-',num2str(jNeuron),') Relative to ',num2str(app.iNeur),' (S:',num2str(iShank),'-',num2str(iShankNeur),') Spikes'])
                        
                        iSubFig = iSubFig + 1;
                        if iSubFig > nSubFigs
                            iSubFig = 1;
                        end
                        
                    end
                end
                
            end
        end

        % Value changed function: VoltageRangeuVEditField
        function VoltageRangeuVEditFieldValueChanged(app, event)
            if ~isempty(app.shankWaveforms)
                
                % Get the indexes
                iShank = app.neurList(app.iNeur,1);
                iShankNeur = app.neurList(app.iNeur,2);
                
                % Plot the waveforms on the shank
                cla(app.UIAxes)
                hold(app.UIAxes,'on')
                wfs = app.shankWaveforms{iShank}{iShankNeur};
                awfs = app.shankAveWaveforms{iShank}{iShankNeur};
                nChannels = size(wfs,3);
                nExWaveforms = size(wfs,4);
                xMin = inf;
                xMax = -inf;
                yMin = inf;
                yMax = -inf;
                dv = app.VoltageRangeuVEditField.Value; % Update the voltage range from the user
                for iChannel = 1:nChannels
                    % Convert time to physical dimensions on shank
                    t1 = wfs(1,1,1,1);
                    t2 = wfs(1,end,1,1);
                    dx1 = app.shankGeo{iShank}(iChannel,1) - app.waveformWindowSize(iShank,1);
                    dx2 = app.shankGeo{iShank}(iChannel,1) + app.waveformWindowSize(iShank,1);
                    mx = (dx2 - dx1)/(t2 - t1);
                    bx = dx1 - (mx*t1);
                    x = mx*wfs(1,:,iChannel,1) + bx;
                    xMin = min([xMin,min(x)]);
                    xMax = max([xMax,max(x)]);
                    
                    % Convert voltage to physical dimensions on the shank
                    dy1 = app.shankGeo{iShank}(iChannel,2) - app.waveformWindowSize(iShank,2);
                    dy2 = app.shankGeo{iShank}(iChannel,2) + app.waveformWindowSize(iShank,2);
                    my = (dy2 - dy1)/dv;
                    by = dy1 - (my*(-0.5*dv));
                    yMin = min([yMin,dy1]);
                    yMax = max([yMax,dy2]);
                    
                    % Plot the channels
                    for iExWaveform = 1:min([app.NumberofWaveformsEditField.Value,size(wfs,4)])
                        if app.neurStage1Ruling{iShank}(iChannel) == 0
                            plot(app.UIAxes,x,my*wfs(2,:,iChannel,iExWaveform) + by,'g','LineWidth',0.2)
                        elseif app.neurStage1Ruling{iShank}(iChannel) == 2
                            plot(app.UIAxes,x,my*wfs(2,:,iChannel,iExWaveform) + by,'r','LineWidth',0.2)
                        end
                    end
                    
                    % Plot the average waveform
                    if app.neurStage1Ruling{iShank}(iChannel) == 0
                        plot(app.UIAxes,x,my*awfs(:,iChannel) + by,'k','LineWidth',1.5)
                    elseif app.neurStage1Ruling{iShank}(iChannel) == 2
                        plot(app.UIAxes,x,my*awfs(:,iChannel) + by,'r','LineWidth',1.5)
                    end
                end
                dx = xMax - xMin;
                dy = yMax - yMin;
                app.UIAxes.XLim = [xMin - 0.1*dx,xMax + 0.1*dx];
                app.UIAxes.YLim = [yMin - 0.1*dy,yMax + 0.1*dy];
                app.xMinumEditField.Value = xMin - 0.1*dx;
                app.xMinumEditField_2.Value = xMax + 0.1*dx;
                app.xMinumEditField_3.Value = yMin - 0.1*dy;
                app.xMinumEditField_4.Value = yMax + 0.1*dy;
                app.VoltageRangeuVEditField.Value = dv; % Update voltage range display
                app.TimeRangeLabel.Text = ['Shank Time Range: ',num2str(t2 - t1),' ms'];
                
                % Draw voltage and time bars in lower left corner for reference
                line(app.UIAxes,[xMin - 0.05*dx,xMin - 0.05*dx],[yMin - 0.05*dy,yMin - 0.05*dy + (dy2 - dy1)],'Color','k','LineWidth',1.5)
                line(app.UIAxes,[xMin - 0.05*dx,xMin - 0.05*dx + (dx2 - dx1)],[yMin - 0.05*dy,yMin - 0.05*dy],'Color','k','LineWidth',1.5)
                
                
            end
        end

        % Value changed function: TimeRangemsEditField
        function TimeRangemsEditFieldValueChanged(app, event)
            if ~isempty(app.shankWaveforms)
                
                % Get the indexes
                iShank = app.neurList(app.iNeur,1);
                iShankNeur = app.neurList(app.iNeur,2);
                
                % Plot the auto-correlation
                if app.TimeRangemsEditField.Value > app.neurAC{iShank}(1,end)
                    app.TimeRangemsEditField.Value = app.neurAC{iShank}(1,end);
                end
                x = app.neurAC{iShank}(1,:);
                y = app.neurAC{iShank}(iShankNeur + 1,(x >= (-app.TimeRangemsEditField.Value)) & (x <= app.TimeRangemsEditField.Value));
                x = x((x >= (-app.TimeRangemsEditField.Value)) & (x <= app.TimeRangemsEditField.Value));
                bar(app.UIAxes2,x,y,'hist','k')
                app.UIAxes2.XLim = [x(1),x(end)];
                app.UIAxes2.YLim = [0,1.1*max(y)];
                
            end
        end

        % Value changed function: NumberofWaveformsEditField
        function NumberofWaveformsEditFieldValueChanged(app, event)
            if ~isempty(app.shankWaveforms)
                
                % Update the neuron index
                iShank = app.neurList(app.iNeur,1);
                iShankNeur = app.neurList(app.iNeur,2);
                
                if app.NumberofWaveformsEditField.Value > size(app.shankWaveforms{iShank}{iShankNeur},4)
                    app.NumberofWaveformsEditField.Value = size(app.shankWaveforms{iShank}{iShankNeur},4);
                end
                
                % Plot the waveforms on the shank
                cla(app.UIAxes)
                hold(app.UIAxes,'on')
                wfs = app.shankWaveforms{iShank}{iShankNeur};
                awfs = app.shankAveWaveforms{iShank}{iShankNeur};
                nChannels = size(wfs,3);
                nExWaveforms = size(wfs,4);
                xMin = inf;
                xMax = -inf;
                yMin = inf;
                yMax = -inf;
                %tempV = app.shankWaveforms{iShank}{iShankNeur}(2,:,:,:);
                %v1 = min(tempV(:));
                %v2 = max(tempV(:));
                v1 = min(awfs(:));
                v2 = max(awfs(:));
                dv = v2 - v1;
                for iChannel = 1:nChannels
                    % Convert time to physical dimensions on shank
                    t1 = wfs(1,1,1,1);
                    t2 = wfs(1,end,1,1);
                    dx1 = app.shankGeo{iShank}(iChannel,1) - app.waveformWindowSize(iShank,1);
                    dx2 = app.shankGeo{iShank}(iChannel,1) + app.waveformWindowSize(iShank,1);
                    mx = (dx2 - dx1)/(t2 - t1);
                    bx = dx1 - (mx*t1);
                    x = mx*wfs(1,:,iChannel,1) + bx;
                    xMin = min([xMin,min(x)]);
                    xMax = max([xMax,max(x)]);
                    
                    % Convert voltage to physical dimensions on the shank
                    dy1 = app.shankGeo{iShank}(iChannel,2) - app.waveformWindowSize(iShank,2);
                    dy2 = app.shankGeo{iShank}(iChannel,2) + app.waveformWindowSize(iShank,2);
                    my = (dy2 - dy1)/dv;
                    by = dy1 - (my*v1);
                    yMin = min([yMin,dy1]);
                    yMax = max([yMax,dy2]);
                    
                    % Plot the channels
                    for iExWaveform = 1:min([app.NumberofWaveformsEditField.Value,size(wfs,4)])
                        if app.neurStage1Ruling{iShank}(iChannel) == 0
                            plot(app.UIAxes,x,my*wfs(2,:,iChannel,iExWaveform) + by,'g','LineWidth',0.2)
                        elseif app.neurStage1Ruling{iShank}(iChannel) == 2
                            plot(app.UIAxes,x,my*wfs(2,:,iChannel,iExWaveform) + by,'r','LineWidth',0.2)
                        end
                    end
                    
                    % Plot the average waveform
                    if app.neurStage1Ruling{iShank}(iChannel) == 0
                        plot(app.UIAxes,x,my*awfs(:,iChannel) + by,'k','LineWidth',1.5)
                    elseif app.neurStage1Ruling{iShank}(iChannel) == 2
                        plot(app.UIAxes,x,my*awfs(:,iChannel) + by,'r','LineWidth',1.5)
                    end
                end
                dx = xMax - xMin;
                dy = yMax - yMin;
                app.UIAxes.XLim = [xMin - 0.1*dx,xMax + 0.1*dx];
                app.UIAxes.YLim = [yMin - 0.1*dy,yMax + 0.1*dy];
                app.xMinumEditField.Value = xMin - 0.1*dx;
                app.xMinumEditField_2.Value = xMax + 0.1*dx;
                app.xMinumEditField_3.Value = yMin - 0.1*dy;
                app.xMinumEditField_4.Value = yMax + 0.1*dy;
                app.VoltageRangeuVEditField.Value = dv; % Update voltage range display
                app.TimeRangeLabel.Text = ['Shank Time Range: ',num2str(t2 - t1),' ms'];
                
                % Draw voltage and time bars in lower left corner for reference
                line(app.UIAxes,[xMin - 0.05*dx,xMin - 0.05*dx],[yMin - 0.05*dy,yMin - 0.05*dy + (dy2 - dy1)],'Color','k','LineWidth',1.5)
                line(app.UIAxes,[xMin - 0.05*dx,xMin - 0.05*dx + (dx2 - dx1)],[yMin - 0.05*dy,yMin - 0.05*dy],'Color','k','LineWidth',1.5)
                
            end
        end

        % Value changed function: GoToNeuronEditField
        function GoToNeuronEditFieldValueChanged(app, event)
            if ~isempty(app.shankWaveforms)
                
                % Correct for bad entries
                if app.GoToNeuronEditField.Value < 1
                    app.GoToNeuronEditField.Value = 1;
                end
                if app.GoToNeuronEditField.Value > size(app.neurList,1)
                    app.GoToNeuronEditField.Value = size(app.neurList,1);
                end
                app.GoToNeuronEditField.Value = round(app.GoToNeuronEditField.Value);
                
                % Update the neuron index
                app.iNeurPrev = app.iNeur;
                app.iNeur = app.GoToNeuronEditField.Value;
                iShank = app.neurList(app.iNeur,1);
                iShankNeur = app.neurList(app.iNeur,2);
                
                % Update the total number of neurons initial label
                app.TotalNeuronsInitialLabel.Text = ['Total Neurons (Initial): ',num2str(length(app.neurList))];
                
                % Update the total number of neurons final label
                nTotNeurons = nnz(app.neurRuling == 1) - nnz(app.mergeOrder ~= (1:length(app.neurList))');
                app.TotalNeuronsFinalLabel.Text = ['Total Neurons (Final): ',num2str(nTotNeurons)];
                
                % Update the neuron counter label
                app.NeurNumLabel.Text = num2str(app.iNeur);
                
                % Update the merge order
                app.MergeIntoNeuronEditField.Value = app.mergeOrder(app.iNeur);
                
                % Update the merged label
                if app.mergeOrder(app.iNeur) ~= app.iNeur
                    app.NotMergedLabel.Text = 'Merged';
                    app.NotMergedLabel.FontColor = 'r';
                else
                    app.NotMergedLabel.Text = 'Not Merged';
                    app.NotMergedLabel.FontColor = 'k';
                end
                
                % Update merge suggestion
                if isempty(app.mergeGuide{iShank}{iShankNeur})
                    app.MergeSuggestionLabel.Text = ['Merge Suggestion',newline,'No Merge'];
                    app.MergeSuggestionLabel.FontColor = 'k';
                elseif isequal(app.mergeGuide{iShank}{iShankNeur},0)
                    app.MergeSuggestionLabel.Text = ['Merge Suggestion',newline,'Noise'];
                    app.MergeSuggestionLabel.FontColor = 'b';
                elseif length(app.mergeGuide{iShank}{iShankNeur}) > 1
                    app.MergeSuggestionLabel.Text = ['Merge Suggestion',newline,'Split'];
                    app.MergeSuggestionLabel.FontColor = 'm';
                else
                    mergeTarget = find((app.neurList(:,1) == iShank) & (app.neurList(:,2) == app.mergeGuide{iShank}{iShankNeur}));
                    app.MergeSuggestionLabel.Text = ['Merge Suggestion',newline,num2str(mergeTarget)];
                    app.MergeSuggestionLabel.FontColor = 'r';
                end
                
                % Update the neuron information fields
                app.ShankLabel.Text = ['Shank: ',num2str(iShank)];
                app.ShankNeuronNumberLabel.Text = ['Shank Neuron Number: ',num2str(iShankNeur)];
                app.NumberofSpikesLabel.Text = ['Number of Spikes: ',num2str(app.neurNSpk{iShank}(iShankNeur))];
                app.FiringRatehzLabel.Text = ['Firing Rate (hz): ',num2str(app.neurFR{iShank}(iShankNeur))];
                app.RefractoryViolatorsLabel.Text = ['Refractory Violators: ',num2str(app.neurRefVio{iShank}(iShankNeur))];
                app.RefViolPercentageLabel.Text = ['%Ref.Viol.: ', num2str((app.neurRefVio{iShank}(iShankNeur)*100)/app.neurNSpk{iShank}(iShankNeur),'%.1f') '%'];
                
                
                % Plot the waveforms on the shank
                cla(app.UIAxes)
                hold(app.UIAxes,'on')
                zoom(app.UIAxes,'on')
                wfs = app.shankWaveforms{iShank}{iShankNeur};
                awfs = app.shankAveWaveforms{iShank}{iShankNeur};
                nChannels = size(wfs,3);
                nExWaveforms = size(wfs,4);
                xMin = inf;
                xMax = -inf;
                yMin = inf;
                yMax = -inf;
                %tempV = app.shankWaveforms{iShank}{iShankNeur}(2,:,:,:);
                %v1 = min(tempV(:));
                %v2 = max(tempV(:));
                v1 = min(awfs(:));
                v2 = max(awfs(:));
                dv = v2 - v1;
                for iChannel = 1:nChannels
                    % Convert time to physical dimensions on shank
                    t1 = wfs(1,1,1,1);
                    t2 = wfs(1,end,1,1);
                    dx1 = app.shankGeo{iShank}(iChannel,1) - app.waveformWindowSize(iShank,1);
                    dx2 = app.shankGeo{iShank}(iChannel,1) + app.waveformWindowSize(iShank,1);
                    mx = (dx2 - dx1)/(t2 - t1);
                    bx = dx1 - (mx*t1);
                    x = mx*wfs(1,:,iChannel,1) + bx;
                    xMin = min([xMin,min(x)]);
                    xMax = max([xMax,max(x)]);
                    
                    % Convert voltage to physical dimensions on the shank
                    dy1 = app.shankGeo{iShank}(iChannel,2) - app.waveformWindowSize(iShank,2);
                    dy2 = app.shankGeo{iShank}(iChannel,2) + app.waveformWindowSize(iShank,2);
                    my = (dy2 - dy1)/dv;
                    by = dy1 - (my*v1);
                    yMin = min([yMin,dy1]);
                    yMax = max([yMax,dy2]);
                    
                    % Plot the channels
                    for iExWaveform = 1:min([app.NumberofWaveformsEditField.Value,size(wfs,4)])
                        if app.neurStage1Ruling{iShank}(iChannel) == 0
                            plot(app.UIAxes,x,my*wfs(2,:,iChannel,iExWaveform) + by,'g','LineWidth',0.2)
                        elseif app.neurStage1Ruling{iShank}(iChannel) == 2
                            plot(app.UIAxes,x,my*wfs(2,:,iChannel,iExWaveform) + by,'r','LineWidth',0.2)
                        end
                    end
                    
                    % Plot the average waveform
                    if app.neurStage1Ruling{iShank}(iChannel) == 0
                        plot(app.UIAxes,x,my*awfs(:,iChannel) + by,'k','LineWidth',1.5)
                    elseif app.neurStage1Ruling{iShank}(iChannel) == 2
                        plot(app.UIAxes,x,my*awfs(:,iChannel) + by,'r','LineWidth',1.5)
                    end
                end
                dx = xMax - xMin;
                dy = yMax - yMin;
                app.UIAxes.XLim = [xMin - 0.1*dx,xMax + 0.1*dx];
                app.UIAxes.YLim = [yMin - 0.1*dy,yMax + 0.1*dy];
                app.xMinumEditField.Value = xMin - 0.1*dx;
                app.xMinumEditField_2.Value = xMax + 0.1*dx;
                app.xMinumEditField_3.Value = yMin - 0.1*dy;
                app.xMinumEditField_4.Value = yMax + 0.1*dy;
                app.VoltageRangeuVEditField.Value = dv; % Update voltage range display
                app.TimeRangeLabel.Text = ['Shank Time Range: ',num2str(t2 - t1),' ms'];
                
                % Draw voltage and time bars in lower left corner for reference
                line(app.UIAxes,[xMin - 0.05*dx,xMin - 0.05*dx],[yMin - 0.05*dy,yMin - 0.05*dy + (dy2 - dy1)],'Color','k','LineWidth',1.5)
                line(app.UIAxes,[xMin - 0.05*dx,xMin - 0.05*dx + (dx2 - dx1)],[yMin - 0.05*dy,yMin - 0.05*dy],'Color','k','LineWidth',1.5)


                % Plot the auto-correlation
                if app.TimeRangemsEditField.Value > app.neurAC{iShank}(1,end)
                    app.TimeRangemsEditField.Value = app.neurAC{iShank}(1,end);
                end
                x = app.neurAC{iShank}(1,:);
                y = app.neurAC{iShank}(iShankNeur + 1,(x >= (-app.TimeRangemsEditField.Value)) & (x <= app.TimeRangemsEditField.Value));
                x = x((x >= (-app.TimeRangemsEditField.Value)) & (x <= app.TimeRangemsEditField.Value));
                bar(app.UIAxes2,x,y,'hist','k')
                app.UIAxes2.XLim = [x(1),x(end)];
                if max(y) == 0
                    app.UIAxes2.YLim = [0,10];
                else
                    app.UIAxes2.YLim = [0,1.1*max(y)];
                end
                
                % Deal with the neuron ruling
                if app.neurRuling(app.iNeur) ~= 0
                    % The neuron ruling has been previously set, so just display the selection.
                    if app.neurRuling(app.iNeur) == 1
                        app.AcceptButton.Value = true;
                        app.RejectButton.Value = false;
                        app.FlagButton.Value = false;
                    elseif app.neurRuling(app.iNeur) == 2
                        app.AcceptButton.Value = false;
                        app.RejectButton.Value = true;
                        app.FlagButton.Value = false;
                    elseif app.neurRuling(app.iNeur) == 3
                        app.AcceptButton.Value = false;
                        app.RejectButton.Value = false;
                        app.FlagButton.Value = true;
                    end
                else
                    % The neuron has not been ruled on, so assume it will be accepted for now.
                    app.neurRuling(app.iNeur) = 2;
                    app.AcceptButton.Value = false;
                    app.RejectButton.Value = true;
                    app.FlagButton.Value = false;
                end
                
                % Update the neuron listing
                nNeurons = size(app.neurList,1);
                tempItems = cell([1,nNeurons]);
                for iNeuron = 1:nNeurons
                    tString = [num2str(iNeuron),' (S: ',num2str(app.neurList(iNeuron,1)),'-',num2str(app.neurList(iNeuron,2)),')'];
                    if app.mergeOrder(iNeuron) ~= iNeuron
                        tString = [tString,' (M into ',num2str(app.mergeOrder(iNeuron)),')'];
                    end
                    if app.neurRuling(iNeuron) == 1 % Accepted
                        tString = [tString,' -Accept-'];
                    elseif app.neurRuling(iNeuron) == 2 % Rejected
                        tString = [tString,' -Reject-'];
                    elseif app.neurRuling(iNeuron) == 3 % Flagged
                        tString = [tString,' -Flag-'];
                    end
                    tempItems{iNeuron} = tString;
                end
                app.NeuronsListBox.Items = tempItems;
                app.NeuronsListBox.Value = tempItems{app.iNeur};
                
                % Update the similar neuron listing
                simList = find(app.neurList(:,1) == app.neurList(app.iNeur,1));
                simList = setdiff(simList,app.iNeur);
                nNeurons = length(simList);
                simScore = sum(app.shankDistWaveforms{app.neurList(app.iNeur,1)}(app.neurList(app.iNeur,2),:,3))*ones([nNeurons,1]);
                for jNeuron = 1:nNeurons
                    simScore(jNeuron) = simScore(jNeuron)/sum(squeeze(app.shankWaveformDifs{app.neurList(app.iNeur,1)}(app.neurList(app.iNeur,2),app.neurList(simList(jNeuron),2),:)));
                end
                [simScore,I] = sort(simScore,'descend');
                simList = simList(I);
                tempItems = cell([1,nNeurons]);
                for iNeuron = 1:nNeurons
                    tString = [num2str(simList(iNeuron)),' (Sim Score: ',num2str(simScore(iNeuron),4),')'];
                    if app.mergeOrder(iNeuron) ~= iNeuron
                        tString = [tString,' (M into ',num2str(app.mergeOrder(simList(iNeuron))),')'];
                    end
                    if app.neurRuling(simList(iNeuron)) == 1 % Accepted
                        tString = [tString,' -Accept-'];
                    elseif app.neurRuling(simList(iNeuron)) == 2 % Rejected
                        tString = [tString,' -Reject-'];
                    elseif app.neurRuling(simList(iNeuron)) == 3 % Flagged
                        tString = [tString,' -Flag-'];
                    end
                    tempItems{iNeuron} = tString;
                end
                app.SimilarNeuronsListBox.Items = tempItems;
                app.SimilarNeuronsListBox.Value = {};
                
            end
        end

        % Button pushed function: PrevViewedNeuronButton
        function PrevViewedNeuronButtonPushed(app, event)
            if ~isempty(app.shankWaveforms)
                
                % Update the neuron index
                tempiNeur = app.iNeur;
                app.iNeur = app.iNeurPrev;
                app.iNeurPrev = tempiNeur;
                iShank = app.neurList(app.iNeur,1);
                iShankNeur = app.neurList(app.iNeur,2);
                
                % Update the total number of neurons initial label
                app.TotalNeuronsInitialLabel.Text = ['Total Neurons (Initial): ',num2str(length(app.neurList))];
                
                % Update the total number of neurons final label
                nTotNeurons = nnz(app.neurRuling == 1) - nnz(app.mergeOrder ~= (1:length(app.neurList))');
                app.TotalNeuronsFinalLabel.Text = ['Total Neurons (Final): ',num2str(nTotNeurons)];
                
                % Update the neuron counter label
                app.NeurNumLabel.Text = num2str(app.iNeur);
                
                % Update the merge order
                app.MergeIntoNeuronEditField.Value = app.mergeOrder(app.iNeur);
                
                % Update the merged label
                if app.mergeOrder(app.iNeur) ~= app.iNeur
                    app.NotMergedLabel.Text = 'Merged';
                    app.NotMergedLabel.FontColor = 'r';
                else
                    app.NotMergedLabel.Text = 'Not Merged';
                    app.NotMergedLabel.FontColor = 'k';
                end
                
                % Update merge suggestion
                if isempty(app.mergeGuide{iShank}{iShankNeur})
                    app.MergeSuggestionLabel.Text = ['Merge Suggestion',newline,'No Merge'];
                    app.MergeSuggestionLabel.FontColor = 'k';
                elseif isequal(app.mergeGuide{iShank}{iShankNeur},0)
                    app.MergeSuggestionLabel.Text = ['Merge Suggestion',newline,'Noise'];
                    app.MergeSuggestionLabel.FontColor = 'b';
                elseif length(app.mergeGuide{iShank}{iShankNeur}) > 1
                    app.MergeSuggestionLabel.Text = ['Merge Suggestion',newline,'Split'];
                    app.MergeSuggestionLabel.FontColor = 'm';
                else
                    mergeTarget = find((app.neurList(:,1) == iShank) & (app.neurList(:,2) == app.mergeGuide{iShank}{iShankNeur}));
                    app.MergeSuggestionLabel.Text = ['Merge Suggestion',newline,num2str(mergeTarget)];
                    app.MergeSuggestionLabel.FontColor = 'r';
                end
                
                % Update the neuron information fields
                app.ShankLabel.Text = ['Shank: ',num2str(iShank)];
                app.ShankNeuronNumberLabel.Text = ['Shank Neuron Number: ',num2str(iShankNeur)];
                app.NumberofSpikesLabel.Text = ['Number of Spikes: ',num2str(app.neurNSpk{iShank}(iShankNeur))];
                app.FiringRatehzLabel.Text = ['Firing Rate (hz): ',num2str(app.neurFR{iShank}(iShankNeur))];
                app.RefractoryViolatorsLabel.Text = ['Refractory Violators: ',num2str(app.neurRefVio{iShank}(iShankNeur))];
                app.RefViolPercentageLabel.Text = ['%Ref.Viol.: ', num2str((app.neurRefVio{iShank}(iShankNeur)*100)/app.neurNSpk{iShank}(iShankNeur),'%.1f') '%'];
                
                
                % Plot the waveforms on the shank
                cla(app.UIAxes)
                hold(app.UIAxes,'on')
                zoom(app.UIAxes,'on')
                wfs = app.shankWaveforms{iShank}{iShankNeur};
                awfs = app.shankAveWaveforms{iShank}{iShankNeur};
                nChannels = size(wfs,3);
                nExWaveforms = size(wfs,4);
                xMin = inf;
                xMax = -inf;
                yMin = inf;
                yMax = -inf;
                %tempV = app.shankWaveforms{iShank}{iShankNeur}(2,:,:,:);
                %v1 = min(tempV(:));
                %v2 = max(tempV(:));
                v1 = min(awfs(:));
                v2 = max(awfs(:));
                dv = v2 - v1;
                for iChannel = 1:nChannels
                    % Convert time to physical dimensions on shank
                    t1 = wfs(1,1,1,1);
                    t2 = wfs(1,end,1,1);
                    dx1 = app.shankGeo{iShank}(iChannel,1) - app.waveformWindowSize(iShank,1);
                    dx2 = app.shankGeo{iShank}(iChannel,1) + app.waveformWindowSize(iShank,1);
                    mx = (dx2 - dx1)/(t2 - t1);
                    bx = dx1 - (mx*t1);
                    x = mx*wfs(1,:,iChannel,1) + bx;
                    xMin = min([xMin,min(x)]);
                    xMax = max([xMax,max(x)]);
                    
                    % Convert voltage to physical dimensions on the shank
                    dy1 = app.shankGeo{iShank}(iChannel,2) - app.waveformWindowSize(iShank,2);
                    dy2 = app.shankGeo{iShank}(iChannel,2) + app.waveformWindowSize(iShank,2);
                    my = (dy2 - dy1)/dv;
                    by = dy1 - (my*v1);
                    yMin = min([yMin,dy1]);
                    yMax = max([yMax,dy2]);
                    
                    % Plot the channels
                    for iExWaveform = 1:min([app.NumberofWaveformsEditField.Value,size(wfs,4)])
                        if app.neurStage1Ruling{iShank}(iChannel) == 0
                            plot(app.UIAxes,x,my*wfs(2,:,iChannel,iExWaveform) + by,'g','LineWidth',0.2)
                        elseif app.neurStage1Ruling{iShank}(iChannel) == 2
                            plot(app.UIAxes,x,my*wfs(2,:,iChannel,iExWaveform) + by,'r','LineWidth',0.2)
                        end
                    end
                    
                    % Plot the average waveform
                    if app.neurStage1Ruling{iShank}(iChannel) == 0
                        plot(app.UIAxes,x,my*awfs(:,iChannel) + by,'k','LineWidth',1.5)
                    elseif app.neurStage1Ruling{iShank}(iChannel) == 2
                        plot(app.UIAxes,x,my*awfs(:,iChannel) + by,'r','LineWidth',1.5)
                    end
                end
                dx = xMax - xMin;
                dy = yMax - yMin;
                app.UIAxes.XLim = [xMin - 0.1*dx,xMax + 0.1*dx];
                app.UIAxes.YLim = [yMin - 0.1*dy,yMax + 0.1*dy];
                app.xMinumEditField.Value = xMin - 0.1*dx;
                app.xMinumEditField_2.Value = xMax + 0.1*dx;
                app.xMinumEditField_3.Value = yMin - 0.1*dy;
                app.xMinumEditField_4.Value = yMax + 0.1*dy;
                app.VoltageRangeuVEditField.Value = dv; % Update voltage range display
                app.TimeRangeLabel.Text = ['Shank Time Range: ',num2str(t2 - t1),' ms'];
                
                % Draw voltage and time bars in lower left corner for reference
                line(app.UIAxes,[xMin - 0.05*dx,xMin - 0.05*dx],[yMin - 0.05*dy,yMin - 0.05*dy + (dy2 - dy1)],'Color','k','LineWidth',1.5)
                line(app.UIAxes,[xMin - 0.05*dx,xMin - 0.05*dx + (dx2 - dx1)],[yMin - 0.05*dy,yMin - 0.05*dy],'Color','k','LineWidth',1.5)


                % Plot the auto-correlation
                if app.TimeRangemsEditField.Value > app.neurAC{iShank}(1,end)
                    app.TimeRangemsEditField.Value = app.neurAC{iShank}(1,end);
                end
                x = app.neurAC{iShank}(1,:);
                y = app.neurAC{iShank}(iShankNeur + 1,(x >= (-app.TimeRangemsEditField.Value)) & (x <= app.TimeRangemsEditField.Value));
                x = x((x >= (-app.TimeRangemsEditField.Value)) & (x <= app.TimeRangemsEditField.Value));
                bar(app.UIAxes2,x,y,'hist','k')
                app.UIAxes2.XLim = [x(1),x(end)];
                if max(y) == 0
                    app.UIAxes2.YLim = [0,10];
                else
                    app.UIAxes2.YLim = [0,1.1*max(y)];
                end
                
                % Deal with the neuron ruling
                if app.neurRuling(app.iNeur) ~= 0
                    % The neuron ruling has been previously set, so just display the selection.
                    if app.neurRuling(app.iNeur) == 1
                        app.AcceptButton.Value = true;
                        app.RejectButton.Value = false;
                        app.FlagButton.Value = false;
                    elseif app.neurRuling(app.iNeur) == 2
                        app.AcceptButton.Value = false;
                        app.RejectButton.Value = true;
                        app.FlagButton.Value = false;
                    elseif app.neurRuling(app.iNeur) == 3
                        app.AcceptButton.Value = false;
                        app.RejectButton.Value = false;
                        app.FlagButton.Value = true;
                    end
                else
                    % The neuron has not been ruled on, so assume it will be accepted for now.
                    app.neurRuling(app.iNeur) = 2;
                    app.AcceptButton.Value = false;
                    app.RejectButton.Value = true;
                    app.FlagButton.Value = false;
                end
                
                % Update the neuron listing
                nNeurons = size(app.neurList,1);
                tempItems = cell([1,nNeurons]);
                for iNeuron = 1:nNeurons
                    tString = [num2str(iNeuron),' (S: ',num2str(app.neurList(iNeuron,1)),'-',num2str(app.neurList(iNeuron,2)),')'];
                    if app.mergeOrder(iNeuron) ~= iNeuron
                        tString = [tString,' (M into ',num2str(app.mergeOrder(iNeuron)),')'];
                    end
                    if app.neurRuling(iNeuron) == 1 % Accepted
                        tString = [tString,' -Accept-'];
                    elseif app.neurRuling(iNeuron) == 2 % Rejected
                        tString = [tString,' -Reject-'];
                    elseif app.neurRuling(iNeuron) == 3 % Flagged
                        tString = [tString,' -Flag-'];
                    end
                    tempItems{iNeuron} = tString;
                end
                app.NeuronsListBox.Items = tempItems;
                app.NeuronsListBox.Value = tempItems{app.iNeur};
                
                % Update the similar neuron listing
                simList = find(app.neurList(:,1) == app.neurList(app.iNeur,1));
                simList = setdiff(simList,app.iNeur);
                nNeurons = length(simList);
                simScore = sum(app.shankDistWaveforms{app.neurList(app.iNeur,1)}(app.neurList(app.iNeur,2),:,3))*ones([nNeurons,1]);
                for jNeuron = 1:nNeurons
                    simScore(jNeuron) = simScore(jNeuron)/sum(squeeze(app.shankWaveformDifs{app.neurList(app.iNeur,1)}(app.neurList(app.iNeur,2),app.neurList(simList(jNeuron),2),:)));
                end
                [simScore,I] = sort(simScore,'descend');
                simList = simList(I);
                tempItems = cell([1,nNeurons]);
                for iNeuron = 1:nNeurons
                    tString = [num2str(simList(iNeuron)),' (Sim Score: ',num2str(simScore(iNeuron),4),')'];
                    if app.mergeOrder(iNeuron) ~= iNeuron
                        tString = [tString,' (M into ',num2str(app.mergeOrder(simList(iNeuron))),')'];
                    end
                    if app.neurRuling(simList(iNeuron)) == 1 % Accepted
                        tString = [tString,' -Accept-'];
                    elseif app.neurRuling(simList(iNeuron)) == 2 % Rejected
                        tString = [tString,' -Reject-'];
                    elseif app.neurRuling(simList(iNeuron)) == 3 % Flagged
                        tString = [tString,' -Flag-'];
                    end
                    tempItems{iNeuron} = tString;
                end
                app.SimilarNeuronsListBox.Items = tempItems;
                app.SimilarNeuronsListBox.Value = {};
                
            end
        end

        % Value changed function: xMinumEditField
        function xMinumEditFieldValueChanged(app, event)
            
            app.UIAxes.XLim(1) = app.xMinumEditField.Value;
            
        end

        % Value changed function: xMinumEditField_2
        function xMinumEditField_2ValueChanged(app, event)
            
            app.UIAxes.XLim(2) = app.xMinumEditField_2.Value;
            
        end

        % Value changed function: xMinumEditField_3
        function xMinumEditField_3ValueChanged(app, event)
            
            app.UIAxes.YLim(1) = app.xMinumEditField_3.Value;
            
        end

        % Value changed function: xMinumEditField_4
        function xMinumEditField_4ValueChanged(app, event)
            
            app.UIAxes.YLim(2) = app.xMinumEditField_4.Value;
            
        end

        % Selection changed function: NeuronRulingButtonGroup
        function NeuronRulingButtonGroupSelectionChanged(app, event)
            if ~isempty(app.shankWaveforms)
                selectedButton = app.NeuronRulingButtonGroup.SelectedObject;
                if strcmp(selectedButton.Text,'Accept')
                    app.neurRuling(app.iNeur) = 1;
                elseif strcmp(selectedButton.Text,'Reject')
                    app.neurRuling(app.iNeur) = 2;
                elseif strcmp(selectedButton.Text,'Flag')
                    app.neurRuling(app.iNeur) = 3;
                end
                
                % Update the neuron listing
                nNeurons = size(app.neurList,1);
                tempItems = cell([1,nNeurons]);
                for iNeuron = 1:nNeurons
                    tString = [num2str(iNeuron),' (S: ',num2str(app.neurList(iNeuron,1)),'-',num2str(app.neurList(iNeuron,2)),')'];
                    if app.mergeOrder(iNeuron) ~= iNeuron
                        tString = [tString,' (M into ',num2str(app.mergeOrder(iNeuron)),')'];
                    end
                    if app.neurRuling(iNeuron) == 1 % Accepted
                        tString = [tString,' -Accept-'];
                    elseif app.neurRuling(iNeuron) == 2 % Rejected
                        tString = [tString,' -Reject-'];
                    elseif app.neurRuling(iNeuron) == 3 % Flagged
                        tString = [tString,' -Flag-'];
                    end
                    tempItems{iNeuron} = tString;
                end
                app.NeuronsListBox.Items = tempItems;
                app.NeuronsListBox.Value = tempItems{app.iNeur};
                
                % Update the total number of neurons initial label
                app.TotalNeuronsInitialLabel.Text = ['Total Neurons (Initial): ',num2str(length(app.neurList))];
                
                % Update the total number of neurons final label
                nTotNeurons = nnz(app.neurRuling == 1) - nnz(app.mergeOrder ~= (1:length(app.neurList))');
                app.TotalNeuronsFinalLabel.Text = ['Total Neurons (Final): ',num2str(nTotNeurons)];
                
            end
        end

        % Button pushed function: NeuronStatusButton
        function NeuronStatusButtonPushed(app, event)
            if ~isempty(app.shankWaveforms)
                
                % Calculate sizing for the list
                nNeurons = size(app.neurList,1);
                if nNeurons <= 100
                    nCols = 4;
                    nRows = ceil(nNeurons/nCols);
                else
                    nRows = 25;
                    nCols = ceil(nNeurons/nRows);
                end
                
                figure
                hold on
                
                iNeuron = 1;
                fontSize = 10;
                for iCol = 1:nCols
                    for iRow = 1:nRows
                        if iNeuron <= nNeurons
                            tString = [num2str(iNeuron),' (S: ',num2str(app.neurList(iNeuron,1)),'-',num2str(app.neurList(iNeuron,2)),')'];
                            if app.mergeOrder(iNeuron) ~= iNeuron
                                tString = [tString,' (M into ',num2str(app.mergeOrder(iNeuron)),')'];
                            end
                            tHandle = text(iCol,nRows - iRow + 1,tString);
                            tHandle.HorizontalAlignment = 'center';
                            tHandle.VerticalAlignment = 'middle';
                            tHandle.FontSize = fontSize;
                            tHandle.Margin = 1;
                            if app.neurRuling(iNeuron) == 0 % Not reviewed yet
                                tHandle.Color = 'k';
                                tHandle.BackgroundColor = 'none';
                            elseif app.neurRuling(iNeuron) == 1 % Accepted
                                tHandle.Color = 'k';
                                tHandle.BackgroundColor = [0,200,0]/255; % Darker Green
                            elseif app.neurRuling(iNeuron) == 2 % Rejected
                                tHandle.Color = 'w';
                                tHandle.BackgroundColor = 'r';
                            elseif app.neurRuling(iNeuron) == 3 % Flagged
                                tHandle.Color = 'w';
                                tHandle.BackgroundColor = [148,0,211]/255; % Purple
                            end
                            iNeuron = iNeuron + 1;
                        end
                    end
                end
                
                % Add a legend
                tHandle = text(1,nRows + 2,'Not Reviewed');
                tHandle.HorizontalAlignment = 'center';
                tHandle.VerticalAlignment = 'middle';
                tHandle.Margin = 1;
                tHandle.FontSize = fontSize;
                tHandle.Color = 'k';
                tHandle.BackgroundColor = 'none';
                
                tHandle = text(2,nRows + 2,'Accepted');
                tHandle.HorizontalAlignment = 'center';
                tHandle.VerticalAlignment = 'middle';
                tHandle.Margin = 1;
                tHandle.FontSize = fontSize;
                tHandle.Color = 'k';
                tHandle.BackgroundColor = [0,200,0]/255;
                
                tHandle = text(3,nRows + 2,'Rejected');
                tHandle.HorizontalAlignment = 'center';
                tHandle.VerticalAlignment = 'middle';
                tHandle.Margin = 1;
                tHandle.FontSize = fontSize;
                tHandle.Color = 'w';
                tHandle.BackgroundColor = 'r';
                
                tHandle = text(4,nRows + 2,'Flagged');
                tHandle.HorizontalAlignment = 'center';
                tHandle.VerticalAlignment = 'middle';
                tHandle.Margin = 1;
                tHandle.FontSize = fontSize;
                tHandle.Color = 'w';
                tHandle.BackgroundColor = [148,0,211]/255;
                
                
                xlim([0,nCols + 1])
                ylim([0,nRows + 3])
                title('Neuron Status (Overall Number (S:Shank-Neuron on Shank))')
                
                
            end
        end

        % Button pushed function: SaveReportButton
        function SaveReportButtonPushed(app, event)
            if ~isempty(app.shankWaveforms)
                % Retrieve the correct variables
                neurRuling = app.neurRuling;
                mergeOrder = app.mergeOrder;
                outputFileName = [app.pathName,filesep,'Stage2Post',app.fileName(10:end)];
                
                % Save the data
                save(outputFileName,'neurRuling','mergeOrder')
                
                % Display the name of the file that was saved
                app.OutputFileLabel.Text = ['Output File: Stage2Post',app.fileName(10:end)];
                
                % Warn the user that unruled or merely flagged neurons will be rejected
                if nnz(ismember([0,3],app.neurRuling)) > 0
                    msgbox('Warning: Not all neurons rejected or accepted. Neurons flagged or not reviewed will be rejected.');
                end
            end
        end

        % Value changed function: MergeIntoNeuronEditField
        function MergeIntoNeuronEditFieldValueChanged(app, event)
            if ~isempty(app.shankWaveforms)
                
                % Correct for bad entries
                if app.MergeIntoNeuronEditField.Value < 1
                    app.MergeIntoNeuronEditField.Value = 1;
                end
                if app.MergeIntoNeuronEditField.Value > size(app.neurList,1)
                    app.MergeIntoNeuronEditField.Value = size(app.neurList,1);
                end
                app.MergeIntoNeuronEditField.Value = round(app.MergeIntoNeuronEditField.Value);
                
                
                % Update the merge order variable with this change.
                app.mergeOrder(app.iNeur) = app.MergeIntoNeuronEditField.Value;
                
                % Update the merged label
                if app.mergeOrder(app.iNeur) ~= app.iNeur
                    app.NotMergedLabel.Text = 'Merged';
                    app.NotMergedLabel.FontColor = 'r';
                else
                    app.NotMergedLabel.Text = 'Not Merged';
                    app.NotMergedLabel.FontColor = 'k';
                end
                
                % Update the neuron listing
                nNeurons = size(app.neurList,1);
                tempItems = cell([1,nNeurons]);
                for iNeuron = 1:nNeurons
                    tString = [num2str(iNeuron),' (S: ',num2str(app.neurList(iNeuron,1)),'-',num2str(app.neurList(iNeuron,2)),')'];
                    if app.mergeOrder(iNeuron) ~= iNeuron
                        tString = [tString,' (M into ',num2str(app.mergeOrder(iNeuron)),')'];
                    end
                    if app.neurRuling(iNeuron) == 1 % Accepted
                        tString = [tString,' -Accept-'];
                    elseif app.neurRuling(iNeuron) == 2 % Rejected
                        tString = [tString,' -Reject-'];
                    elseif app.neurRuling(iNeuron) == 3 % Flagged
                        tString = [tString,' -Flag-'];
                    end
                    tempItems{iNeuron} = tString;
                end
                app.NeuronsListBox.Items = tempItems;
                app.NeuronsListBox.Value = tempItems{app.iNeur};
                
                % Update the similar neuron listing
                simList = find(app.neurList(:,1) == app.neurList(app.iNeur,1));
                simList = setdiff(simList,app.iNeur);
                nNeurons = length(simList);
                simScore = sum(app.shankDistWaveforms{app.neurList(app.iNeur,1)}(app.neurList(app.iNeur,2),:,3))*ones([nNeurons,1]);
                for jNeuron = 1:nNeurons
                    simScore(jNeuron) = simScore(jNeuron)/sum(squeeze(app.shankWaveformDifs{app.neurList(app.iNeur,1)}(app.neurList(app.iNeur,2),app.neurList(simList(jNeuron),2),:)));
                end
                [simScore,I] = sort(simScore,'descend');
                simList = simList(I);
                tempItems = cell([1,nNeurons]);
                for iNeuron = 1:nNeurons
                    tString = [num2str(simList(iNeuron)),' (Sim Score: ',num2str(simScore(iNeuron),4),')'];
                    if app.mergeOrder(iNeuron) ~= iNeuron
                        tString = [tString,' (M into ',num2str(app.mergeOrder(simList(iNeuron))),')'];
                    end
                    if app.neurRuling(simList(iNeuron)) == 1 % Accepted
                        tString = [tString,' -Accept-'];
                    elseif app.neurRuling(simList(iNeuron)) == 2 % Rejected
                        tString = [tString,' -Reject-'];
                    elseif app.neurRuling(simList(iNeuron)) == 3 % Flagged
                        tString = [tString,' -Flag-'];
                    end
                    tempItems{iNeuron} = tString;
                end
                app.SimilarNeuronsListBox.Items = tempItems;
                app.SimilarNeuronsListBox.Value = {};
                
                % Update the total number of neurons initial label
                app.TotalNeuronsInitialLabel.Text = ['Total Neurons (Initial): ',num2str(length(app.neurList))];
                
                % Update the total number of neurons final label
                nTotNeurons = nnz(app.neurRuling == 1) - nnz(app.mergeOrder ~= (1:length(app.neurList))');
                app.TotalNeuronsFinalLabel.Text = ['Total Neurons (Final): ',num2str(nTotNeurons)];
                
            end
        end

        % Button pushed function: DynFiringRateButton
        function DynFiringRateButtonPushed(app, event)
            if ~isempty(app.shankWaveforms)
                
                % Get the indexes
                iShank = app.neurList(app.iNeur,1);
                iShankNeur = app.neurList(app.iNeur,2);
                
                % Get the data to plot
                x = app.neurDynFR{iShank}(1,:);
                y = app.neurDynFR{iShank}(1 + iShankNeur,:);
                
                figure
                
                bar(x,y,'hist','k')
                
                xlim([x(1) - 0.5*(x(2)-x(1)),x(end) + 0.5*(x(2)-x(1))])
                ylim([0,1.1*max(y)])
                title('Firing Rate Through Recording')
                xlabel('Time (min)')
                ylabel('Firing Rate (Hz)')
                
            end
        end

        % Button pushed function: FingerprintButton
        function FingerprintButtonPushed(app, event)
            if ~isempty(app.shankWaveforms)
                
                % Get the indexes
                iShank = app.neurList(app.iNeur,1);
                iShankNeur = app.neurList(app.iNeur,2);
                
                % Organize the data
                nNeurons = size(app.shankWaveformDifs{iShank},2);
                nChannels = size(app.shankWaveformDifs{iShank},3);
                distValues = squeeze(app.shankDistWaveforms{iShank}(iShankNeur,:,:));
                for jNeuron = setdiff(1:nNeurons,iShankNeur)
                    
                    % Find the neuron indexes for the other neuron
                    jShank = iShank;
                    jNeur = find((app.neurList(:,1) == jShank) & (app.neurList(:,2) == jNeuron));
                    
                    % Find the number of channels with differences in average waveforms above difference to noise
                    difAve = squeeze(app.shankWaveformDifs{iShank}(iShankNeur,jNeuron,:));
                    difNoise = distValues(:,1) + distValues(:,2); % mean plus standard deviation
                    difAve(app.neurStage1Ruling{iShank}(:) ~= 0) = []; % only look at channels that were accepted
                    difNoise(app.neurStage1Ruling{iShank}(:) ~= 0) = []; % only look at channels that were accepted
                    
                    
                    if ismember(app.neurRuling(jNeur),[1,3]) && (nnz(difAve > difNoise) <= 2)
                        % Plot if this other neuron is accepted or flagged, and if the waveforms aren't too different
                        figure
                        hold on
                        
                        y = squeeze(app.shankWaveformDifs{iShank}(iShankNeur,jNeuron,:));
                        bar(1:nChannels,y,'hist','k')
                        for iChannel = 1:nChannels
                            line([iChannel,iChannel],[distValues(iChannel,1) - distValues(iChannel,2),distValues(iChannel,1) + distValues(iChannel,2)],'Color','r','LineWidth',2)
                        end
                        scatter(1:nChannels,distValues(:,1)',10,'r','d')
                        scatter(1:nChannels,distValues(:,3)',10,'g','+')
                        scatter(1:nChannels,distValues(:,4)',10,'g','+')
                        
                        yMax = max([max(y),max(distValues(:,1) + distValues(:,2)),max(distValues(:,4))]);
                        
                        xlim([0.5,nChannels + 0.5])
                        ylim([0,1.1*yMax])
                        xlabel('Channels')
                        ylabel('Voltage Difference (uV)')
                        title(['Average Waveform Difference of ',num2str(jNeur),' (S:',num2str(jShank),'-',num2str(jNeuron),') Relative to ',num2str(app.iNeur),' (S:',num2str(iShank),'-',num2str(iShankNeur),')'])
                        
                    end
                end
                
            end
        end

        % Button pushed function: CompareWaveformsButton
        function CompareWaveformsButtonPushed(app, event)
            if ~isempty(app.shankWaveforms)
                
                % Get the indexes
                iShank = app.neurList(app.EditField.Value,1);
                iShankNeur = app.neurList(app.EditField.Value,2);
                jShank = app.neurList(app.EditField_2.Value,1);
                jShankNeur = app.neurList(app.EditField_2.Value,2);
                
                % Set the number of figures in the panel
                nHorFig = 3;
                nVertFig = 2;
                nSubFigs = nHorFig*nVertFig;
                
                nChannels = size(app.shankWaveforms{iShank}{iShankNeur},3);
                x = app.shankWaveforms{iShank}{iShankNeur}(1,:,1,1)';
                yMax = max([app.shankAveWaveforms{iShank}{iShankNeur}(:);app.shankAveWaveforms{jShank}{jShankNeur}(:)]);
                yMin = min([app.shankAveWaveforms{iShank}{iShankNeur}(:);app.shankAveWaveforms{jShank}{jShankNeur}(:)]);
                dy = yMax - yMin;
                
                iSubFig = 1;
                for iChannel = 1:nChannels
                    
                    if iSubFig == 1
                        figure
                    end
                    subplot(nVertFig,nHorFig,iSubFig)
                    hold on
                    
                    y1 = app.shankAveWaveforms{iShank}{iShankNeur}(:,iChannel)';
                    y2 = app.shankAveWaveforms{jShank}{jShankNeur}(:,iChannel)';
                    
                    plot(x,y1,'r')
                    plot(x,y2,'b')
                    
                    xlim([x(1),x(end)])
                    ylim([yMin - 0.1*dy,yMax + 0.1*dy])
                    
                    legend(num2str(app.EditField.Value),num2str(app.EditField_2.Value))
                    xlabel('Time (ms)')
                    ylabel('Voltage (uV)')
                    title(['Channel ',num2str(iChannel)])
                    
                    iSubFig = iSubFig + 1;
                    if iSubFig > nSubFigs
                        iSubFig = 1;
                    end
                    
                end
                
                
            end
        end

        % Value changed function: NeuronsListBox
        function NeuronsListBoxValueChanged(app, event)
            
            if ~isempty(app.shankWaveforms)
                
                % Update the neuron index
                app.iNeurPrev = app.iNeur;
                app.iNeur = str2double(strtok(app.NeuronsListBox.Value));
                iShank = app.neurList(app.iNeur,1);
                iShankNeur = app.neurList(app.iNeur,2);
                
                % Update the total number of neurons initial label
                app.TotalNeuronsInitialLabel.Text = ['Total Neurons (Initial): ',num2str(length(app.neurList))];
                
                % Update the total number of neurons final label
                nTotNeurons = nnz(app.neurRuling == 1) - nnz(app.mergeOrder ~= (1:length(app.neurList))');
                app.TotalNeuronsFinalLabel.Text = ['Total Neurons (Final): ',num2str(nTotNeurons)];
                
                % Update the neuron counter label
                app.NeurNumLabel.Text = num2str(app.iNeur);
                
                % Update the merge order
                app.MergeIntoNeuronEditField.Value = app.mergeOrder(app.iNeur);
                
                % Update the merged label
                if app.mergeOrder(app.iNeur) ~= app.iNeur
                    app.NotMergedLabel.Text = 'Merged';
                    app.NotMergedLabel.FontColor = 'r';
                else
                    app.NotMergedLabel.Text = 'Not Merged';
                    app.NotMergedLabel.FontColor = 'k';
                end
                
                % Update merge suggestion
                if isempty(app.mergeGuide{iShank}{iShankNeur})
                    app.MergeSuggestionLabel.Text = ['Merge Suggestion',newline,'No Merge'];
                    app.MergeSuggestionLabel.FontColor = 'k';
                elseif isequal(app.mergeGuide{iShank}{iShankNeur},0)
                    app.MergeSuggestionLabel.Text = ['Merge Suggestion',newline,'Noise'];
                    app.MergeSuggestionLabel.FontColor = 'b';
                elseif length(app.mergeGuide{iShank}{iShankNeur}) > 1
                    app.MergeSuggestionLabel.Text = ['Merge Suggestion',newline,'Split'];
                    app.MergeSuggestionLabel.FontColor = 'm';
                else
                    mergeTarget = find((app.neurList(:,1) == iShank) & (app.neurList(:,2) == app.mergeGuide{iShank}{iShankNeur}));
                    app.MergeSuggestionLabel.Text = ['Merge Suggestion',newline,num2str(mergeTarget)];
                    app.MergeSuggestionLabel.FontColor = 'r';
                end
                
                % Update the neuron information fields
                app.ShankLabel.Text = ['Shank: ',num2str(iShank)];
                app.ShankNeuronNumberLabel.Text = ['Shank Neuron Number: ',num2str(iShankNeur)];
                app.NumberofSpikesLabel.Text = ['Number of Spikes: ',num2str(app.neurNSpk{iShank}(iShankNeur))];
                app.FiringRatehzLabel.Text = ['Firing Rate (hz): ',num2str(app.neurFR{iShank}(iShankNeur))];
                app.RefractoryViolatorsLabel.Text = ['Refractory Violators: ',num2str(app.neurRefVio{iShank}(iShankNeur))];
                app.RefViolPercentageLabel.Text = ['%Ref.Viol.: ', num2str((app.neurRefVio{iShank}(iShankNeur)*100)/app.neurNSpk{iShank}(iShankNeur),'%.1f') '%'];
                
                
                % Plot the waveforms on the shank
                cla(app.UIAxes)
                hold(app.UIAxes,'on')
                zoom(app.UIAxes,'on')
                wfs = app.shankWaveforms{iShank}{iShankNeur};
                awfs = app.shankAveWaveforms{iShank}{iShankNeur};
                nChannels = size(wfs,3);
                nExWaveforms = size(wfs,4);
                xMin = inf;
                xMax = -inf;
                yMin = inf;
                yMax = -inf;
                %tempV = app.shankWaveforms{iShank}{iShankNeur}(2,:,:,:);
                %v1 = min(tempV(:));
                %v2 = max(tempV(:));
                v1 = min(awfs(:));
                v2 = max(awfs(:));
                dv = v2 - v1;
                for iChannel = 1:nChannels
                    % Convert time to physical dimensions on shank
                    t1 = wfs(1,1,1,1);
                    t2 = wfs(1,end,1,1);
                    dx1 = app.shankGeo{iShank}(iChannel,1) - app.waveformWindowSize(iShank,1);
                    dx2 = app.shankGeo{iShank}(iChannel,1) + app.waveformWindowSize(iShank,1);
                    mx = (dx2 - dx1)/(t2 - t1);
                    bx = dx1 - (mx*t1);
                    x = mx*wfs(1,:,iChannel,1) + bx;
                    xMin = min([xMin,min(x)]);
                    xMax = max([xMax,max(x)]);
                    
                    % Convert voltage to physical dimensions on the shank
                    dy1 = app.shankGeo{iShank}(iChannel,2) - app.waveformWindowSize(iShank,2);
                    dy2 = app.shankGeo{iShank}(iChannel,2) + app.waveformWindowSize(iShank,2);
                    my = (dy2 - dy1)/dv;
                    by = dy1 - (my*v1);
                    yMin = min([yMin,dy1]);
                    yMax = max([yMax,dy2]);
                    
                    % Plot the channels
                    for iExWaveform = 1:min([app.NumberofWaveformsEditField.Value,size(wfs,4)])
                        if app.neurStage1Ruling{iShank}(iChannel) == 0
                            plot(app.UIAxes,x,my*wfs(2,:,iChannel,iExWaveform) + by,'g','LineWidth',0.2)
                        elseif app.neurStage1Ruling{iShank}(iChannel) == 2
                            plot(app.UIAxes,x,my*wfs(2,:,iChannel,iExWaveform) + by,'r','LineWidth',0.2)
                        end
                    end
                    
                    % Plot the average waveform
                    if app.neurStage1Ruling{iShank}(iChannel) == 0
                        plot(app.UIAxes,x,my*awfs(:,iChannel) + by,'k','LineWidth',1.5)
                    elseif app.neurStage1Ruling{iShank}(iChannel) == 2
                        plot(app.UIAxes,x,my*awfs(:,iChannel) + by,'r','LineWidth',1.5)
                    end
                end
                dx = xMax - xMin;
                dy = yMax - yMin;
                app.UIAxes.XLim = [xMin - 0.1*dx,xMax + 0.1*dx];
                app.UIAxes.YLim = [yMin - 0.1*dy,yMax + 0.1*dy];
                app.xMinumEditField.Value = xMin - 0.1*dx;
                app.xMinumEditField_2.Value = xMax + 0.1*dx;
                app.xMinumEditField_3.Value = yMin - 0.1*dy;
                app.xMinumEditField_4.Value = yMax + 0.1*dy;
                app.VoltageRangeuVEditField.Value = dv; % Update voltage range display
                app.TimeRangeLabel.Text = ['Shank Time Range: ',num2str(t2 - t1),' ms'];
                
                % Draw voltage and time bars in lower left corner for reference
                line(app.UIAxes,[xMin - 0.05*dx,xMin - 0.05*dx],[yMin - 0.05*dy,yMin - 0.05*dy + (dy2 - dy1)],'Color','k','LineWidth',1.5)
                line(app.UIAxes,[xMin - 0.05*dx,xMin - 0.05*dx + (dx2 - dx1)],[yMin - 0.05*dy,yMin - 0.05*dy],'Color','k','LineWidth',1.5)


                % Plot the auto-correlation
                if app.TimeRangemsEditField.Value > app.neurAC{iShank}(1,end)
                    app.TimeRangemsEditField.Value = app.neurAC{iShank}(1,end);
                end
                x = app.neurAC{iShank}(1,:);
                y = app.neurAC{iShank}(iShankNeur + 1,(x >= (-app.TimeRangemsEditField.Value)) & (x <= app.TimeRangemsEditField.Value));
                x = x((x >= (-app.TimeRangemsEditField.Value)) & (x <= app.TimeRangemsEditField.Value));
                bar(app.UIAxes2,x,y,'hist','k')
                app.UIAxes2.XLim = [x(1),x(end)];
                if max(y) == 0
                    app.UIAxes2.YLim = [0,10];
                else
                    app.UIAxes2.YLim = [0,1.1*max(y)];
                end
                
                % Deal with the neuron ruling
                if app.neurRuling(app.iNeur) ~= 0
                    % The neuron ruling has been previously set, so just display the selection.
                    if app.neurRuling(app.iNeur) == 1
                        app.AcceptButton.Value = true;
                        app.RejectButton.Value = false;
                        app.FlagButton.Value = false;
                    elseif app.neurRuling(app.iNeur) == 2
                        app.AcceptButton.Value = false;
                        app.RejectButton.Value = true;
                        app.FlagButton.Value = false;
                    elseif app.neurRuling(app.iNeur) == 3
                        app.AcceptButton.Value = false;
                        app.RejectButton.Value = false;
                        app.FlagButton.Value = true;
                    end
                else
                    % The neuron has not been ruled on, so assume it will be accepted for now.
                    app.neurRuling(app.iNeur) = 1;
                    app.AcceptButton.Value = true;
                    app.RejectButton.Value = false;
                    app.FlagButton.Value = false;
                end
                
                % Update the neuron listing
                nNeurons = size(app.neurList,1);
                tempItems = cell([1,nNeurons]);
                for iNeuron = 1:nNeurons
                    tString = [num2str(iNeuron),' (S: ',num2str(app.neurList(iNeuron,1)),'-',num2str(app.neurList(iNeuron,2)),')'];
                    if app.mergeOrder(iNeuron) ~= iNeuron
                        tString = [tString,' (M into ',num2str(app.mergeOrder(iNeuron)),')'];
                    end
                    if app.neurRuling(iNeuron) == 1 % Accepted
                        tString = [tString,' -Accept-'];
                    elseif app.neurRuling(iNeuron) == 2 % Rejected
                        tString = [tString,' -Reject-'];
                    elseif app.neurRuling(iNeuron) == 3 % Flagged
                        tString = [tString,' -Flag-'];
                    end
                    tempItems{iNeuron} = tString;
                end
                app.NeuronsListBox.Items = tempItems;
                app.NeuronsListBox.Value = tempItems{app.iNeur};
                
                % Update the similar neuron listing
                simList = find(app.neurList(:,1) == app.neurList(app.iNeur,1));
                simList = setdiff(simList,app.iNeur);
                nNeurons = length(simList);
                simScore = sum(app.shankDistWaveforms{app.neurList(app.iNeur,1)}(app.neurList(app.iNeur,2),:,3))*ones([nNeurons,1]);
                for jNeuron = 1:nNeurons
                    simScore(jNeuron) = simScore(jNeuron)/sum(squeeze(app.shankWaveformDifs{app.neurList(app.iNeur,1)}(app.neurList(app.iNeur,2),app.neurList(simList(jNeuron),2),:)));
                end
                [simScore,I] = sort(simScore,'descend');
                simList = simList(I);
                tempItems = cell([1,nNeurons]);
                for iNeuron = 1:nNeurons
                    tString = [num2str(simList(iNeuron)),' (Sim Score: ',num2str(simScore(iNeuron),4),')'];
                    if app.mergeOrder(iNeuron) ~= iNeuron
                        tString = [tString,' (M into ',num2str(app.mergeOrder(simList(iNeuron))),')'];
                    end
                    if app.neurRuling(simList(iNeuron)) == 1 % Accepted
                        tString = [tString,' -Accept-'];
                    elseif app.neurRuling(simList(iNeuron)) == 2 % Rejected
                        tString = [tString,' -Reject-'];
                    elseif app.neurRuling(simList(iNeuron)) == 3 % Flagged
                        tString = [tString,' -Flag-'];
                    end
                    tempItems{iNeuron} = tString;
                end
                app.SimilarNeuronsListBox.Items = tempItems;
                app.SimilarNeuronsListBox.Value = {};
                
            end
        end

        % Value changed function: SimilarNeuronsListBox
        function SimilarNeuronsListBoxValueChanged(app, event)
            if ~isempty(app.shankWaveforms)
                
                % Get the indexes
                iShank = app.neurList(str2double(strtok(app.NeuronsListBox.Value)),1);
                iShankNeur = app.neurList(str2double(strtok(app.NeuronsListBox.Value)),2);
                jShank = app.neurList(str2double(strtok(app.SimilarNeuronsListBox.Value)),1);
                jShankNeur = app.neurList(str2double(strtok(app.SimilarNeuronsListBox.Value)),2);
                
                % Plot the average waveforms for comparison
                % Set the number of figures in the panel
                nHorFig = 4;
                nVertFig = 4;
                nSubFigs = nHorFig*nVertFig;
                
                nChannels = size(app.shankWaveforms{iShank}{iShankNeur},3);
                x = app.shankWaveforms{iShank}{iShankNeur}(1,:,1,1)';
                yMax = max([app.shankAveWaveforms{iShank}{iShankNeur}(:);app.shankAveWaveforms{jShank}{jShankNeur}(:)]);
                yMin = min([app.shankAveWaveforms{iShank}{iShankNeur}(:);app.shankAveWaveforms{jShank}{jShankNeur}(:)]);
                dy = yMax - yMin;
                
                iSubFig = 1;
                for iChannel = 1:nChannels
                    
                    if iSubFig == 1
                        figure('units','normalized','outerposition',[0 0 1 1])
                    end
                    subplot(nVertFig,nHorFig,iSubFig)
                    hold on
                    
                    y1 = app.shankAveWaveforms{iShank}{iShankNeur}(:,iChannel)';
                    y2 = app.shankAveWaveforms{jShank}{jShankNeur}(:,iChannel)';
                    
                    plot(x,y1,'r')
                    plot(x,y2,'b')
                    
                    xlim([x(1),x(end)])
                    ylim([yMin - 0.1*dy,yMax + 0.1*dy])
                    
                    legend(strtok(app.NeuronsListBox.Value),strtok(app.SimilarNeuronsListBox.Value))
                    xlabel('Time (ms)')
                    ylabel('Voltage (uV)')
                    title(['Channel ',num2str(iChannel)])
                    
                    iSubFig = iSubFig + 1;
                    if iSubFig > nSubFigs
                        iSubFig = 1;
                    end
                    
                end
                
                % Plot the cross correlation
                
                % Organize the data
                x = squeeze(app.neurSTA{iShank}(1,1,:));
                y = squeeze(app.neurSTA{iShank}(iShankNeur,jShankNeur,:));
                figure('units','normalized','outerposition',[0 0 1 1])
                bar(x,y,'hist','k')
                
                xlim([x(1) - 0.5*(x(2) - x(1)),x(end) + 0.5*(x(2) - x(1))])
                if isequal(y,zeros(size(y)))
                    ylim([0,1])
                else
                    ylim([0,1.1*max(y)])
                end
                xlabel('Time (ms)')
                ylabel('Number of Spikes')
                title(['Activity of ',strtok(app.SimilarNeuronsListBox.Value),' (S:',num2str(jShank),'-',num2str(jShankNeur),') Relative to ',strtok(app.NeuronsListBox.Value),' (S:',num2str(iShank),'-',num2str(iShankNeur),') Spikes'])
                
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Shank Waveforms')
            xlabel(app.UIAxes, 'X (um)')
            ylabel(app.UIAxes, 'Y (um)')
            app.LoadReportButton = uibutton(app.UIFigure, 'push');
            app.LoadReportButton.ButtonPushedFcn = createCallbackFcn(app, @LoadReportButtonPushed, true);
            app.LoadReportButton.Text = 'Load Report';
            app.DataSetLabel = uilabel(app.UIFigure);
            app.DataSetLabel.Text = 'Data Set:';
            app.PreviousNeuronButton = uibutton(app.UIFigure, 'push');
            app.PreviousNeuronButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousNeuronButtonPushed, true);
            app.PreviousNeuronButton.Text = 'Previous Neuron';
            app.NextNeuronButton = uibutton(app.UIFigure, 'push');
            app.NextNeuronButton.ButtonPushedFcn = createCallbackFcn(app, @NextNeuronButtonPushed, true);
            app.NextNeuronButton.Text = 'Next Neuron';
            app.TotalNeuronsInitialLabel = uilabel(app.UIFigure);
            app.TotalNeuronsInitialLabel.Text = 'Total Neurons (Initial): ';
            app.NeurNumLabel = uilabel(app.UIFigure);
            app.NeurNumLabel.Text = 'NeurNum';
            app.ShankLabel = uilabel(app.UIFigure);
            app.ShankLabel.Text = 'Shank: ';
            app.ShankNeuronNumberLabel = uilabel(app.UIFigure);
            app.ShankNeuronNumberLabel.Text = 'Shank Neuron Number:';
            app.RasterButton = uibutton(app.UIFigure, 'push');
            app.RasterButton.ButtonPushedFcn = createCallbackFcn(app, @RasterButtonPushed, true);
            app.RasterButton.Text = 'Raster';
            app.CrossCorrelationsButton = uibutton(app.UIFigure, 'push');
            app.CrossCorrelationsButton.ButtonPushedFcn = createCallbackFcn(app, @CrossCorrelationsButtonPushed, true);
            app.CrossCorrelationsButton.Text = 'Cross Correlations';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Auto-Correlation')
            xlabel(app.UIAxes2, 'time (ms)')
            ylabel(app.UIAxes2, 'number of spikes')
            app.VoltageRangeuVEditFieldLabel = uilabel(app.UIFigure);
            app.VoltageRangeuVEditFieldLabel.Text = 'Voltage Range (uV)';
            app.VoltageRangeuVEditField = uieditfield(app.UIFigure, 'numeric');
            app.VoltageRangeuVEditField.ValueChangedFcn = createCallbackFcn(app, @VoltageRangeuVEditFieldValueChanged, true);
            app.TimeRangeLabel = uilabel(app.UIFigure);
            app.TimeRangeLabel.Text = 'Time Range: ';
            app.TimeRangemsEditFieldLabel = uilabel(app.UIFigure);
            app.TimeRangemsEditFieldLabel.Text = 'Time Range (ms)';
            app.TimeRangemsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeRangemsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeRangemsEditFieldValueChanged, true);
            app.TimeRangemsEditField.Value = 10;
            app.NeuronInformationLabel = uilabel(app.UIFigure);
            app.NeuronInformationLabel.Text = 'Neuron Information';
            app.NumberofSpikesLabel = uilabel(app.UIFigure);
            app.NumberofSpikesLabel.Text = 'Number of Spikes:';
            app.FiringRatehzLabel = uilabel(app.UIFigure);
            app.FiringRatehzLabel.Text = 'Firing Rate (hz): ';
            app.RefractoryViolatorsLabel = uilabel(app.UIFigure);
            app.RefractoryViolatorsLabel.Text = 'Refractory Violators: ';
            app.NumberofWaveformsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofWaveformsEditFieldLabel.Text = 'Number of Waveforms:';
            app.NumberofWaveformsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofWaveformsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofWaveformsEditFieldValueChanged, true);
            app.NumberofWaveformsEditField.Value = 10;
            app.GoToNeuronEditFieldLabel = uilabel(app.UIFigure);
            app.GoToNeuronEditFieldLabel.Text = 'Go To Neuron: ';
            app.GoToNeuronEditField = uieditfield(app.UIFigure, 'numeric');
            app.GoToNeuronEditField.ValueChangedFcn = createCallbackFcn(app, @GoToNeuronEditFieldValueChanged, true);
            app.PrevViewedNeuronButton = uibutton(app.UIFigure, 'push');
            app.PrevViewedNeuronButton.ButtonPushedFcn = createCallbackFcn(app, @PrevViewedNeuronButtonPushed, true);
            app.PrevViewedNeuronButton.Text = 'Prev. Viewed Neuron';
            app.DynFiringRateButton = uibutton(app.UIFigure, 'push');
            app.DynFiringRateButton.ButtonPushedFcn = createCallbackFcn(app, @DynFiringRateButtonPushed, true);
            app.DynFiringRateButton.Text = 'Dyn. Firing Rate';
            app.xMinumEditFieldLabel = uilabel(app.UIFigure);
            app.xMinumEditFieldLabel.Text = 'xMin (um): ';
            app.xMinumEditField = uieditfield(app.UIFigure, 'numeric');
            app.xMinumEditField.ValueChangedFcn = createCallbackFcn(app, @xMinumEditFieldValueChanged, true);
            app.ShankWaveformPlotParametersLabel = uilabel(app.UIFigure);
            app.ShankWaveformPlotParametersLabel.Text = 'Shank Waveform Plot Parameters';
            app.xMinumEditFieldLabel_2 = uilabel(app.UIFigure);
            app.xMinumEditFieldLabel_2.Text = 'xMax (um): ';
            app.xMinumEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.xMinumEditField_2.ValueChangedFcn = createCallbackFcn(app, @xMinumEditField_2ValueChanged, true);
            app.xMinumEditFieldLabel_3 = uilabel(app.UIFigure);
            app.xMinumEditFieldLabel_3.Text = 'yMin (um): ';
            app.xMinumEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.xMinumEditField_3.ValueChangedFcn = createCallbackFcn(app, @xMinumEditField_3ValueChanged, true);
            app.xMinumEditFieldLabel_4 = uilabel(app.UIFigure);
            app.xMinumEditFieldLabel_4.Text = 'yMax (um): ';
            app.xMinumEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.xMinumEditField_4.ValueChangedFcn = createCallbackFcn(app, @xMinumEditField_4ValueChanged, true);
            app.GeneratePlotsLabel = uilabel(app.UIFigure);
            app.GeneratePlotsLabel.Text = 'Generate Plots';
            app.NeuronStatusButton = uibutton(app.UIFigure, 'push');
            app.NeuronStatusButton.ButtonPushedFcn = createCallbackFcn(app, @NeuronStatusButtonPushed, true);
            app.NeuronStatusButton.Text = 'Neuron Status';
            app.NeuronRulingButtonGroup = uibuttongroup(app.UIFigure);
            app.NeuronRulingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NeuronRulingButtonGroupSelectionChanged, true);
            app.NeuronRulingButtonGroup.Title = 'Neuron Ruling';
            app.AcceptButton = uiradiobutton(app.NeuronRulingButtonGroup);
            app.AcceptButton.Text = 'Accept';
            app.AcceptButton.Value = true;
            app.RejectButton = uiradiobutton(app.NeuronRulingButtonGroup);
            app.RejectButton.Text = 'Reject';
            app.FlagButton = uiradiobutton(app.NeuronRulingButtonGroup);
            app.FlagButton.Text = 'Flag';
            app.SaveReportButton = uibutton(app.UIFigure, 'push');
            app.SaveReportButton.ButtonPushedFcn = createCallbackFcn(app, @SaveReportButtonPushed, true);
            app.SaveReportButton.Text = 'Save Report';
            app.OutputFileLabel = uilabel(app.UIFigure);
            app.OutputFileLabel.Text = 'Output File: ';
            app.MergeIntoNeuronEditFieldLabel = uilabel(app.UIFigure);
            app.MergeIntoNeuronEditFieldLabel.Text = 'Merge Into Neuron:';
            app.MergeIntoNeuronEditField = uieditfield(app.UIFigure, 'numeric');
            app.MergeIntoNeuronEditField.ValueChangedFcn = createCallbackFcn(app, @MergeIntoNeuronEditFieldValueChanged, true);
            app.NotMergedLabel = uilabel(app.UIFigure);
            app.NotMergedLabel.Text = 'Not Merged';
            app.FingerprintButton = uibutton(app.UIFigure, 'push');
            app.FingerprintButton.ButtonPushedFcn = createCallbackFcn(app, @FingerprintButtonPushed, true);
            app.FingerprintButton.Text = 'Fingerprint';
            app.CompareWaveformsButton = uibutton(app.UIFigure, 'push');
            app.CompareWaveformsButton.ButtonPushedFcn = createCallbackFcn(app, @CompareWaveformsButtonPushed, true);
            app.CompareWaveformsButton.Text = 'Compare Waveforms';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.vsLabel = uilabel(app.UIFigure);
            app.vsLabel.Text = 'vs.';
            app.NeuronsListBoxLabel = uilabel(app.UIFigure);
            app.NeuronsListBoxLabel.Text = 'Neurons';
            app.NeuronsListBox = uilistbox(app.UIFigure);
            app.NeuronsListBox.Items = {' '};
            app.NeuronsListBox.ValueChangedFcn = createCallbackFcn(app, @NeuronsListBoxValueChanged, true);
            app.NeuronsListBox.Value = ' ';
            app.SimilarNeuronsListBoxLabel = uilabel(app.UIFigure);
            app.SimilarNeuronsListBoxLabel.Text = 'Similar Neurons';
            app.SimilarNeuronsListBox = uilistbox(app.UIFigure);
            app.SimilarNeuronsListBox.Items = {};
            app.SimilarNeuronsListBox.ValueChangedFcn = createCallbackFcn(app, @SimilarNeuronsListBoxValueChanged, true);
            app.SimilarNeuronsListBox.Value = {};
            app.TotalNeuronsFinalLabel = uilabel(app.UIFigure);
            app.TotalNeuronsFinalLabel.Text = 'Total Neurons (Final): ';
            app.MergeSuggestionLabel = uilabel(app.UIFigure);
            app.MergeSuggestionLabel.Text = 'Merge Suggestion';
            app.RefViolPercentageLabel = uilabel(app.UIFigure);
            app.RefViolPercentageLabel.Text = '%Ref.Viol.: ';
        end
    end

    methods (Access = public)

        % Construct app
        function app = stage2Reviewer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end