classdef stage1Reviewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes_3                    matlab.ui.control.UIAxes
        LoadReportButton            matlab.ui.control.Button
        UIAxes_4                    matlab.ui.control.UIAxes
        UIAxes_5                    matlab.ui.control.UIAxes
        UIAxes_6                    matlab.ui.control.UIAxes
        PreviousChannelButton       matlab.ui.control.Button
        NextChannelButton           matlab.ui.control.Button
        DataSetLabel                matlab.ui.control.Label
        StandardDeviationLabel      matlab.ui.control.Label
        OpenEphysChannelLabel       matlab.ui.control.Label
        ChNumLabel                  matlab.ui.control.Label
        ChannelRulingButtonGroup    matlab.ui.container.ButtonGroup
        AcceptButton                matlab.ui.control.RadioButton
        SilenceButton               matlab.ui.control.RadioButton
        QuietButton                 matlab.ui.control.RadioButton
        MinVoltageuVEditFieldLabel  matlab.ui.control.Label
        MinVoltageuVEditField       matlab.ui.control.NumericEditField
        MaxVoltageuVEditFieldLabel  matlab.ui.control.Label
        MaxVoltageuVEditField       matlab.ui.control.NumericEditField
        MinPoweruWEditFieldLabel    matlab.ui.control.Label
        MinPoweruWEditField         matlab.ui.control.NumericEditField
        MaxPoweruWEditFieldLabel    matlab.ui.control.Label
        MaxPoweruWEditField         matlab.ui.control.NumericEditField
        LoadChannelMapButton        matlab.ui.control.Button
        ChannelMapFileLabel         matlab.ui.control.Label
        SaveResultsButton           matlab.ui.control.Button
        OutputFileLabel             matlab.ui.control.Label
        QuickDuplicateButton        matlab.ui.control.Button
        DuplicateFileLabel          matlab.ui.control.Label
        Switch                      matlab.ui.control.Switch
    end

    
    properties (Access = public)
        dataChunk % the dataChunk variable
        power % the power variable
        dataSD % the dataSD variable
        iChan % the channel counter
        chanRuling % the record of channel specific results (NaN: no selection, 0: accept, 1: silence, 2: quiet)
        openE2probe % space to store the site mappings from open ephys to the probe
        probeGeo % space to store the probe channel geometry information
        siteMapNotes % space to store notes about the site mapping
        probeChanShankID % space to store the probe channel shank ID information
        fileName % space to store the name of the loaded file
        pathName % space to store the name of the loaded file path
        irow % switch between filtered and unfiltered chuncks(1: plot unfiltered data, 3: plot filtered data)
        FiltdataThr %threshold for the filtered data (to help visualize spikes)
    end
    

    methods (Access = private)

        % Button pushed function: LoadReportButton
        function LoadReportButtonPushed(app, event)
            [file,path,indx] = uigetfile;
            if ~isequal(file,0)
                
                if strcmp(file(7:9),'Pre') % If a pre-review file was selected, load it
                    load([path,filesep,file],'dataChunk','power','dataSD','FiltdataThr')
                elseif strcmp(file(7:10),'Post') % If a post-review file was selected, load its pre-review file
                    try
                        load([path,filesep,[file(1:6),'Pre',file(11:end)]],'dataChunk','power','dataSD','FiltdataThr')
                    catch
                        error('The appropriate pre-review file must be in this same directory.')
                    end
                else
                    error('File does not have proper name for a stage 1 review file.')
                end
                
                app.iChan = 1;
                app.irow=1;
                
                
                % Store relevant data
                if strcmp(file(7:9),'Pre')
                    app.fileName = file;
                elseif strcmp(file(7:10),'Post')
                    app.fileName = [file(1:6),'Pre',file(11:end)];
                end
                app.pathName = path;
                app.dataChunk = dataChunk;
                app.power = power;
                app.dataSD = dataSD;
                app.chanRuling = NaN([size(app.dataChunk,1),1]);
                if exist('FiltdataThr','var')
                app.FiltdataThr=FiltdataThr;
                end
                if strcmp(file(7:10),'Post')
                    load([path,filesep,file],'chanRuling')
                    app.chanRuling = chanRuling;
                end
                
                app.DataSetLabel.Text = ['Data Set: ',app.fileName];
                
                % Plot the voltage traces
                plot(app.UIAxes,app.dataChunk{app.iChan,1}(2,:),app.dataChunk{app.iChan,1}(app.irow,:));
                app.UIAxes.XLim = [app.dataChunk{app.iChan,1}(2,1),app.dataChunk{app.iChan,1}(2,end)];
                app.UIAxes.YLim = [app.MinVoltageuVEditField.Value,app.MaxVoltageuVEditField.Value];
                plot(app.UIAxes_2,app.dataChunk{app.iChan,2}(2,:),app.dataChunk{app.iChan,2}(app.irow,:));
                app.UIAxes_2.XLim = [app.dataChunk{app.iChan,2}(2,1),app.dataChunk{app.iChan,2}(2,end)];
                app.UIAxes_2.YLim = [app.MinVoltageuVEditField.Value,app.MaxVoltageuVEditField.Value];
                plot(app.UIAxes_3,app.dataChunk{app.iChan,3}(2,:),app.dataChunk{app.iChan,3}(app.irow,:));
                app.UIAxes_3.XLim = [app.dataChunk{app.iChan,3}(2,1),app.dataChunk{app.iChan,3}(2,end)];
                app.UIAxes_3.YLim = [app.MinVoltageuVEditField.Value,app.MaxVoltageuVEditField.Value];
                % If spk filter is on plot threshold
                if app.irow==3
                    hold (app.UIAxes,'on')
                    plot(app.UIAxes,xlim(app.UIAxes),[app.FiltdataThr(app.iChan,1) app.FiltdataThr(app.iChan,1)],'r');
                    plot(app.UIAxes,xlim(app.UIAxes),[-app.FiltdataThr(app.iChan,1) -app.FiltdataThr(app.iChan,1)],'r');
                    hold (app.UIAxes,'off')
                    hold (app.UIAxes_2,'on')
                    plot(app.UIAxes_2,xlim(app.UIAxes_2),[app.FiltdataThr(app.iChan,2) app.FiltdataThr(app.iChan,2)],'r');
                    plot(app.UIAxes_2,xlim(app.UIAxes_2),[-app.FiltdataThr(app.iChan,2) -app.FiltdataThr(app.iChan,2)],'r');
                    hold (app.UIAxes_2,'off')
                    hold (app.UIAxes_3,'on')
                    plot(app.UIAxes_3,xlim(app.UIAxes_3),[app.FiltdataThr(app.iChan,3) app.FiltdataThr(app.iChan,3)],'r');
                    plot(app.UIAxes_3,xlim(app.UIAxes_3),[-app.FiltdataThr(app.iChan,3) -app.FiltdataThr(app.iChan,3)],'r');
                    hold (app.UIAxes_3,'off')
                end
                % Plot the power spectra
                plot(app.UIAxes_4,app.power{app.iChan,1}(2,:),app.power{app.iChan,1}(1,:));
                app.UIAxes_4.XLim = [app.power{app.iChan,1}(2,1),app.power{app.iChan,1}(2,end)];
                app.UIAxes_4.YLim = [app.MinPoweruWEditField.Value,app.MaxPoweruWEditField.Value];
                app.UIAxes_4.XScale = 'log';
                app.UIAxes_4.YScale = 'log';
                plot(app.UIAxes_5,app.power{app.iChan,2}(2,:),app.power{app.iChan,2}(1,:));
                app.UIAxes_5.XLim = [app.power{app.iChan,2}(2,1),app.power{app.iChan,2}(2,end)];
                app.UIAxes_5.YLim = [app.MinPoweruWEditField.Value,app.MaxPoweruWEditField.Value];
                app.UIAxes_5.XScale = 'log';
                app.UIAxes_5.YScale = 'log';
                plot(app.UIAxes_6,app.power{app.iChan,3}(2,:),app.power{app.iChan,3}(1,:));
                app.UIAxes_6.XLim = [app.power{app.iChan,3}(2,1),app.power{app.iChan,3}(2,end)];
                app.UIAxes_6.YLim = [app.MinPoweruWEditField.Value,app.MaxPoweruWEditField.Value];
                app.UIAxes_6.XScale = 'log';
                app.UIAxes_6.YScale = 'log';
                
                % Display the standard deviation
                app.StandardDeviationLabel.Text = ['Standard Deviation: ',num2str(app.dataSD(app.iChan),4),' uV'];
                
                % Display the current channel number
                app.ChNumLabel.Text = num2str(app.iChan);
                
                % Deal with the channel ruling
                if isfinite(app.chanRuling(app.iChan))
                    % The channel ruling has been previously set, so just display the selection.
                    if app.chanRuling(app.iChan) == 0
                        app.AcceptButton.Value = true;
                        app.SilenceButton.Value = false;
                        app.QuietButton.Value = false;
                    elseif app.chanRuling(app.iChan) == 1
                        app.AcceptButton.Value = false;
                        app.SilenceButton.Value = true;
                        app.QuietButton.Value = false;
                    elseif app.chanRuling(app.iChan) == 2
                        app.AcceptButton.Value = false;
                        app.SilenceButton.Value = false;
                        app.QuietButton.Value = true;
                    end
                else
                    % The channel has not been ruled on, so assume it will be accepted for now.
                    app.chanRuling(app.iChan) = 0;
                    app.AcceptButton.Value = true;
                    app.SilenceButton.Value = false;
                    app.QuietButton.Value = false;
                end
                
            end
        end

        % Button pushed function: PreviousChannelButton
        function PreviousChannelButtonPushed(app, event)
            if ~isempty(app.dataChunk)
                
                if app.iChan > 1
                    
                    % Reduce the channel number
                    app.iChan = app.iChan - 1;
                    
                    
                    % Plot the voltage traces
                    plot(app.UIAxes,app.dataChunk{app.iChan,1}(2,:),app.dataChunk{app.iChan,1}(app.irow,:));
                    app.UIAxes.XLim = [app.dataChunk{app.iChan,1}(2,1),app.dataChunk{app.iChan,1}(2,end)];
                    app.UIAxes.YLim = [app.MinVoltageuVEditField.Value,app.MaxVoltageuVEditField.Value];
                    plot(app.UIAxes_2,app.dataChunk{app.iChan,2}(2,:),app.dataChunk{app.iChan,2}(app.irow,:));
                    app.UIAxes_2.XLim = [app.dataChunk{app.iChan,2}(2,1),app.dataChunk{app.iChan,2}(2,end)];
                    app.UIAxes_2.YLim = [app.MinVoltageuVEditField.Value,app.MaxVoltageuVEditField.Value];
                    plot(app.UIAxes_3,app.dataChunk{app.iChan,3}(2,:),app.dataChunk{app.iChan,3}(app.irow,:));
                    app.UIAxes_3.XLim = [app.dataChunk{app.iChan,3}(2,1),app.dataChunk{app.iChan,3}(2,end)];
                    app.UIAxes_3.YLim = [app.MinVoltageuVEditField.Value,app.MaxVoltageuVEditField.Value];
                    % If spk filter is on plot threshold
                    if app.irow==3
                        hold (app.UIAxes,'on')
                        plot(app.UIAxes,xlim(app.UIAxes),[app.FiltdataThr(app.iChan,1) app.FiltdataThr(app.iChan,1)],'r');
                        plot(app.UIAxes,xlim(app.UIAxes),[-app.FiltdataThr(app.iChan,1) -app.FiltdataThr(app.iChan,1)],'r');
                        hold (app.UIAxes,'off')
                        hold (app.UIAxes_2,'on')
                        plot(app.UIAxes_2,xlim(app.UIAxes_2),[app.FiltdataThr(app.iChan,2) app.FiltdataThr(app.iChan,2)],'r');
                        plot(app.UIAxes_2,xlim(app.UIAxes_2),[-app.FiltdataThr(app.iChan,2) -app.FiltdataThr(app.iChan,2)],'r');
                        hold (app.UIAxes_2,'off')
                        hold (app.UIAxes_3,'on')
                        plot(app.UIAxes_3,xlim(app.UIAxes_3),[app.FiltdataThr(app.iChan,3) app.FiltdataThr(app.iChan,3)],'r');
                        plot(app.UIAxes_3,xlim(app.UIAxes_3),[-app.FiltdataThr(app.iChan,3) -app.FiltdataThr(app.iChan,3)],'r');
                        hold (app.UIAxes_3,'off')
                    end
                    % Plot the power spectra
                    plot(app.UIAxes_4,app.power{app.iChan,1}(2,:),app.power{app.iChan,1}(1,:));
                    app.UIAxes_4.XLim = [app.power{app.iChan,1}(2,1),app.power{app.iChan,1}(2,end)];
                    app.UIAxes_4.YLim = [app.MinPoweruWEditField.Value,app.MaxPoweruWEditField.Value];
                    app.UIAxes_4.XScale = 'log';
                    app.UIAxes_4.YScale = 'log';
                    plot(app.UIAxes_5,app.power{app.iChan,2}(2,:),app.power{app.iChan,2}(1,:));
                    app.UIAxes_5.XLim = [app.power{app.iChan,2}(2,1),app.power{app.iChan,2}(2,end)];
                    app.UIAxes_5.YLim = [app.MinPoweruWEditField.Value,app.MaxPoweruWEditField.Value];
                    app.UIAxes_5.XScale = 'log';
                    app.UIAxes_5.YScale = 'log';
                    plot(app.UIAxes_6,app.power{app.iChan,3}(2,:),app.power{app.iChan,3}(1,:));
                    app.UIAxes_6.XLim = [app.power{app.iChan,3}(2,1),app.power{app.iChan,3}(2,end)];
                    app.UIAxes_6.YLim = [app.MinPoweruWEditField.Value,app.MaxPoweruWEditField.Value];
                    app.UIAxes_6.XScale = 'log';
                    app.UIAxes_6.YScale = 'log';
                    
                    % Display the standard deviation
                    app.StandardDeviationLabel.Text = ['Standard Deviation: ',num2str(app.dataSD(app.iChan),4),' uV'];
                    
                    % Display the current channel number
                    app.ChNumLabel.Text = num2str(app.iChan);
                    
                    % Deal with the channel ruling
                    if isfinite(app.chanRuling(app.iChan))
                        % The channel ruling has been previously set, so just display the selection.
                        if app.chanRuling(app.iChan) == 0
                            app.AcceptButton.Value = true;
                            app.SilenceButton.Value = false;
                            app.QuietButton.Value = false;
                        elseif app.chanRuling(app.iChan) == 1
                            app.AcceptButton.Value = false;
                            app.SilenceButton.Value = true;
                            app.QuietButton.Value = false;
                        elseif app.chanRuling(app.iChan) == 2
                            app.AcceptButton.Value = false;
                            app.SilenceButton.Value = false;
                            app.QuietButton.Value = true;
                        end
                    else
                        % The channel has not been ruled on, so assume it will be accepted for now.
                        app.chanRuling(app.iChan) = 0;
                        app.AcceptButton.Value = true;
                        app.SilenceButton.Value = false;
                        app.QuietButton.Value = false;
                    end
                    
                    
                end
            end
        end

        % Button pushed function: NextChannelButton
        function NextChannelButtonPushed(app, event)
            
            if ~isempty(app.dataChunk)
                
                if app.iChan < size(app.dataChunk,1)
                    
                    % Advance the channel number
                    app.iChan = app.iChan + 1;
                    
                    
                    % Plot the voltage traces
                    plot(app.UIAxes,app.dataChunk{app.iChan,1}(2,:),app.dataChunk{app.iChan,1}(app.irow,:));
                    app.UIAxes.XLim = [app.dataChunk{app.iChan,1}(2,1),app.dataChunk{app.iChan,1}(2,end)];
                    app.UIAxes.YLim = [app.MinVoltageuVEditField.Value,app.MaxVoltageuVEditField.Value];
                    plot(app.UIAxes_2,app.dataChunk{app.iChan,2}(2,:),app.dataChunk{app.iChan,2}(app.irow,:));
                    app.UIAxes_2.XLim = [app.dataChunk{app.iChan,2}(2,1),app.dataChunk{app.iChan,2}(2,end)];
                    app.UIAxes_2.YLim = [app.MinVoltageuVEditField.Value,app.MaxVoltageuVEditField.Value];
                    plot(app.UIAxes_3,app.dataChunk{app.iChan,3}(2,:),app.dataChunk{app.iChan,3}(app.irow,:));
                    app.UIAxes_3.XLim = [app.dataChunk{app.iChan,3}(2,1),app.dataChunk{app.iChan,3}(2,end)];
                    app.UIAxes_3.YLim = [app.MinVoltageuVEditField.Value,app.MaxVoltageuVEditField.Value];
                    % If spk filter is on plot threshold
                    if app.irow==3
                        hold (app.UIAxes,'on')
                        plot(app.UIAxes,xlim(app.UIAxes),[app.FiltdataThr(app.iChan,1) app.FiltdataThr(app.iChan,1)],'r');
                        plot(app.UIAxes,xlim(app.UIAxes),[-app.FiltdataThr(app.iChan,1) -app.FiltdataThr(app.iChan,1)],'r');
                        hold (app.UIAxes,'off')
                        hold (app.UIAxes_2,'on')
                        plot(app.UIAxes_2,xlim(app.UIAxes_2),[app.FiltdataThr(app.iChan,2) app.FiltdataThr(app.iChan,2)],'r');
                        plot(app.UIAxes_2,xlim(app.UIAxes_2),[-app.FiltdataThr(app.iChan,2) -app.FiltdataThr(app.iChan,2)],'r');
                        hold (app.UIAxes_2,'off')
                        hold (app.UIAxes_3,'on')
                        plot(app.UIAxes_3,xlim(app.UIAxes_3),[app.FiltdataThr(app.iChan,3) app.FiltdataThr(app.iChan,3)],'r');
                        plot(app.UIAxes_3,xlim(app.UIAxes_3),[-app.FiltdataThr(app.iChan,3) -app.FiltdataThr(app.iChan,3)],'r');
                        hold (app.UIAxes_3,'off')
                    end
                    % Plot the power spectra
                    plot(app.UIAxes_4,app.power{app.iChan,1}(2,:),app.power{app.iChan,1}(1,:));
                    app.UIAxes_4.XLim = [app.power{app.iChan,1}(2,1),app.power{app.iChan,1}(2,end)];
                    app.UIAxes_4.YLim = [app.MinPoweruWEditField.Value,app.MaxPoweruWEditField.Value];
                    app.UIAxes_4.XScale = 'log';
                    app.UIAxes_4.YScale = 'log';
                    plot(app.UIAxes_5,app.power{app.iChan,2}(2,:),app.power{app.iChan,2}(1,:));
                    app.UIAxes_5.XLim = [app.power{app.iChan,2}(2,1),app.power{app.iChan,2}(2,end)];
                    app.UIAxes_5.YLim = [app.MinPoweruWEditField.Value,app.MaxPoweruWEditField.Value];
                    app.UIAxes_5.XScale = 'log';
                    app.UIAxes_5.YScale = 'log';
                    plot(app.UIAxes_6,app.power{app.iChan,3}(2,:),app.power{app.iChan,3}(1,:));
                    app.UIAxes_6.XLim = [app.power{app.iChan,3}(2,1),app.power{app.iChan,3}(2,end)];
                    app.UIAxes_6.YLim = [app.MinPoweruWEditField.Value,app.MaxPoweruWEditField.Value];
                    app.UIAxes_6.XScale = 'log';
                    app.UIAxes_6.YScale = 'log';
                    
                    % Display the standard deviation
                    app.StandardDeviationLabel.Text = ['Standard Deviation: ',num2str(app.dataSD(app.iChan),4),' uV'];
                    
                    % Display the current channel number
                    app.ChNumLabel.Text = num2str(app.iChan);
                    
                    % Deal with the channel ruling
                    if isfinite(app.chanRuling(app.iChan))
                        % The channel ruling has been previously set, so just display the selection.
                        if app.chanRuling(app.iChan) == 0
                            app.AcceptButton.Value = true;
                            app.SilenceButton.Value = false;
                            app.QuietButton.Value = false;
                        elseif app.chanRuling(app.iChan) == 1
                            app.AcceptButton.Value = false;
                            app.SilenceButton.Value = true;
                            app.QuietButton.Value = false;
                        elseif app.chanRuling(app.iChan) == 2
                            app.AcceptButton.Value = false;
                            app.SilenceButton.Value = false;
                            app.QuietButton.Value = true;
                        end
                    else
                        % The channel has not been ruled on, so assume it will be accepted for now.
                        app.chanRuling(app.iChan) = 0;
                        app.AcceptButton.Value = true;
                        app.SilenceButton.Value = false;
                        app.QuietButton.Value = false;
                    end
                    
                end
            end
        end

        % Selection changed function: ChannelRulingButtonGroup
        function ChannelRulingButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChannelRulingButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Silence')
                app.chanRuling(app.iChan) = 1;
            elseif strcmp(selectedButton.Text,'Accept')
                app.chanRuling(app.iChan) = 0;
            elseif strcmp(selectedButton.Text,'Quiet')
                app.chanRuling(app.iChan) = 2;
            end
        end

        % Value changed function: MinVoltageuVEditField
        function MinVoltageuVEditFieldValueChanged(app, event)
            plot(app.UIAxes,app.dataChunk{app.iChan,1}(2,:),app.dataChunk{app.iChan,1}(app.irow,:));
            app.UIAxes.XLim = [app.dataChunk{app.iChan,1}(2,1),app.dataChunk{app.iChan,1}(2,end)];
            app.UIAxes.YLim = [app.MinVoltageuVEditField.Value,app.MaxVoltageuVEditField.Value];
            plot(app.UIAxes_2,app.dataChunk{app.iChan,2}(2,:),app.dataChunk{app.iChan,2}(app.irow,:));
            app.UIAxes_2.XLim = [app.dataChunk{app.iChan,2}(2,1),app.dataChunk{app.iChan,2}(2,end)];
            app.UIAxes_2.YLim = [app.MinVoltageuVEditField.Value,app.MaxVoltageuVEditField.Value];
            plot(app.UIAxes_3,app.dataChunk{app.iChan,3}(2,:),app.dataChunk{app.iChan,3}(app.irow,:));
            app.UIAxes_3.XLim = [app.dataChunk{app.iChan,3}(2,1),app.dataChunk{app.iChan,3}(2,end)];
            app.UIAxes_3.YLim = [app.MinVoltageuVEditField.Value,app.MaxVoltageuVEditField.Value];
            % If spk filter is on plot threshold
            if app.irow==3
                hold (app.UIAxes,'on')
                plot(app.UIAxes,xlim(app.UIAxes),[app.FiltdataThr(app.iChan,1) app.FiltdataThr(app.iChan,1)],'r');
                plot(app.UIAxes,xlim(app.UIAxes),[-app.FiltdataThr(app.iChan,1) -app.FiltdataThr(app.iChan,1)],'r');
                hold (app.UIAxes,'off')
                hold (app.UIAxes_2,'on')
                plot(app.UIAxes_2,xlim(app.UIAxes_2),[app.FiltdataThr(app.iChan,2) app.FiltdataThr(app.iChan,2)],'r');
                plot(app.UIAxes_2,xlim(app.UIAxes_2),[-app.FiltdataThr(app.iChan,2) -app.FiltdataThr(app.iChan,2)],'r');
                hold (app.UIAxes_2,'off')
                hold (app.UIAxes_3,'on')
                plot(app.UIAxes_3,xlim(app.UIAxes_3),[app.FiltdataThr(app.iChan,3) app.FiltdataThr(app.iChan,3)],'r');
                plot(app.UIAxes_3,xlim(app.UIAxes_3),[-app.FiltdataThr(app.iChan,3) -app.FiltdataThr(app.iChan,3)],'r');
                hold (app.UIAxes_3,'off')
            end
        end

        % Value changed function: MaxVoltageuVEditField
        function MaxVoltageuVEditFieldValueChanged(app, event)
            plot(app.UIAxes,app.dataChunk{app.iChan,1}(2,:),app.dataChunk{app.iChan,1}(app.irow,:));
            app.UIAxes.XLim = [app.dataChunk{app.iChan,1}(2,1),app.dataChunk{app.iChan,1}(2,end)];
            app.UIAxes.YLim = [app.MinVoltageuVEditField.Value,app.MaxVoltageuVEditField.Value];
            plot(app.UIAxes_2,app.dataChunk{app.iChan,2}(2,:),app.dataChunk{app.iChan,2}(app.irow,:));
            app.UIAxes_2.XLim = [app.dataChunk{app.iChan,2}(2,1),app.dataChunk{app.iChan,2}(2,end)];
            app.UIAxes_2.YLim = [app.MinVoltageuVEditField.Value,app.MaxVoltageuVEditField.Value];
            plot(app.UIAxes_3,app.dataChunk{app.iChan,3}(2,:),app.dataChunk{app.iChan,3}(app.irow,:));
            app.UIAxes_3.XLim = [app.dataChunk{app.iChan,3}(2,1),app.dataChunk{app.iChan,3}(2,end)];
            app.UIAxes_3.YLim = [app.MinVoltageuVEditField.Value,app.MaxVoltageuVEditField.Value];
            % If spk filter is on plot threshold
            if app.irow==3
                hold (app.UIAxes,'on')
                plot(app.UIAxes,xlim(app.UIAxes),[app.FiltdataThr(app.iChan,1) app.FiltdataThr(app.iChan,1)],'r');
                plot(app.UIAxes,xlim(app.UIAxes),[-app.FiltdataThr(app.iChan,1) -app.FiltdataThr(app.iChan,1)],'r');
                hold (app.UIAxes,'off')
                hold (app.UIAxes_2,'on')
                plot(app.UIAxes_2,xlim(app.UIAxes_2),[app.FiltdataThr(app.iChan,2) app.FiltdataThr(app.iChan,2)],'r');
                plot(app.UIAxes_2,xlim(app.UIAxes_2),[-app.FiltdataThr(app.iChan,2) -app.FiltdataThr(app.iChan,2)],'r');
                hold (app.UIAxes_2,'off')
                hold (app.UIAxes_3,'on')
                plot(app.UIAxes_3,xlim(app.UIAxes_3),[app.FiltdataThr(app.iChan,3) app.FiltdataThr(app.iChan,3)],'r');
                plot(app.UIAxes_3,xlim(app.UIAxes_3),[-app.FiltdataThr(app.iChan,3) -app.FiltdataThr(app.iChan,3)],'r');
                hold (app.UIAxes_3,'off')
            end
        end

        % Value changed function: MinPoweruWEditField
        function MinPoweruWEditFieldValueChanged(app, event)
            plot(app.UIAxes_4,app.power{app.iChan,1}(2,:),app.power{app.iChan,1}(1,:));
            app.UIAxes_4.XLim = [app.power{app.iChan,1}(2,1),app.power{app.iChan,1}(2,end)];
            app.UIAxes_4.YLim = [app.MinPoweruWEditField.Value,app.MaxPoweruWEditField.Value];
            app.UIAxes_4.XScale = 'log';
            app.UIAxes_4.YScale = 'log';
            plot(app.UIAxes_5,app.power{app.iChan,2}(2,:),app.power{app.iChan,2}(1,:));
            app.UIAxes_5.XLim = [app.power{app.iChan,2}(2,1),app.power{app.iChan,2}(2,end)];
            app.UIAxes_5.YLim = [app.MinPoweruWEditField.Value,app.MaxPoweruWEditField.Value];
            app.UIAxes_5.XScale = 'log';
            app.UIAxes_5.YScale = 'log';
            plot(app.UIAxes_6,app.power{app.iChan,3}(2,:),app.power{app.iChan,3}(1,:));
            app.UIAxes_6.XLim = [app.power{app.iChan,3}(2,1),app.power{app.iChan,3}(2,end)];
            app.UIAxes_6.YLim = [app.MinPoweruWEditField.Value,app.MaxPoweruWEditField.Value];
            app.UIAxes_6.XScale = 'log';
            app.UIAxes_6.YScale = 'log';
        end

        % Value changed function: MaxPoweruWEditField
        function MaxPoweruWEditFieldValueChanged(app, event)
            plot(app.UIAxes_4,app.power{app.iChan,1}(2,:),app.power{app.iChan,1}(1,:));
            app.UIAxes_4.XLim = [app.power{app.iChan,1}(2,1),app.power{app.iChan,1}(2,end)];
            app.UIAxes_4.YLim = [app.MinPoweruWEditField.Value,app.MaxPoweruWEditField.Value];
            app.UIAxes_4.XScale = 'log';
            app.UIAxes_4.YScale = 'log';
            plot(app.UIAxes_5,app.power{app.iChan,2}(2,:),app.power{app.iChan,2}(1,:));
            app.UIAxes_5.XLim = [app.power{app.iChan,2}(2,1),app.power{app.iChan,2}(2,end)];
            app.UIAxes_5.YLim = [app.MinPoweruWEditField.Value,app.MaxPoweruWEditField.Value];
            app.UIAxes_5.XScale = 'log';
            app.UIAxes_5.YScale = 'log';
            plot(app.UIAxes_6,app.power{app.iChan,3}(2,:),app.power{app.iChan,3}(1,:));
            app.UIAxes_6.XLim = [app.power{app.iChan,3}(2,1),app.power{app.iChan,3}(2,end)];
            app.UIAxes_6.YLim = [app.MinPoweruWEditField.Value,app.MaxPoweruWEditField.Value];
            app.UIAxes_6.XScale = 'log';
            app.UIAxes_6.YScale = 'log';
        end

        % Button pushed function: LoadChannelMapButton
        function LoadChannelMapButtonPushed(app, event)
            [file,path,indx] = uigetfile;
            if ~isequal(file,0)
                
                % Load the site mapping file and store the relevant variables
                load([path,filesep,file],'openE2probe','probeGeo','siteMapNotes','probeChanShankID')
                app.openE2probe = openE2probe;
                app.probeGeo = probeGeo;
                app.siteMapNotes = siteMapNotes;
                app.probeChanShankID = probeChanShankID;
                
                % Display the file selected
                app.ChannelMapFileLabel.Text = ['Channel Map File: ',file];
                
                % Display the notes for the user
                msgbox({'Site Mapping Notes:';app.siteMapNotes});
            end
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            if ~isempty(app.dataChunk)
                % Retrieve the correct variables
                chanRuling = app.chanRuling;
                openE2probe = app.openE2probe;
                probeGeo = app.probeGeo;
                siteMapNotes = app.siteMapNotes;
                probeChanShankID = app.probeChanShankID;
                outputFileName = [app.pathName,filesep,'Stage1Post',app.fileName(10:end)];
                
                % Save the data
                save(outputFileName,'chanRuling','openE2probe','probeGeo','siteMapNotes','probeChanShankID')
                
                % Display the name of the file that was saved
                app.OutputFileLabel.Text = ['Output File: Stage1Post',app.fileName(10:end)];
            end
        end

        % Button pushed function: QuickDuplicateButton
        function QuickDuplicateButtonPushed(app, event)
            if ~isempty(app.dataChunk)
                [file,path,indx] = uigetfile;
                if ~isequal(file,0)
                    
                    % Retrieve the correct variables
                    chanRuling = app.chanRuling;
                    openE2probe = app.openE2probe;
                    probeGeo = app.probeGeo;
                    siteMapNotes = app.siteMapNotes;
                    probeChanShankID = app.probeChanShankID;
                    outputFileName = [path,filesep,'Stage1Post',file(10:end)];
                    
                    % Save the data
                    save(outputFileName,'chanRuling','openE2probe','probeGeo','siteMapNotes','probeChanShankID')
                    
                    % Display the name of the file that was saved
                    app.DuplicateFileLabel.Text = ['Duplicate File: Stage1Post',file(10:end)];
                end
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            app.irow=1;
            Spkfilter = app.Switch.Value;
            %check filter switch
            if strcmp(Spkfilter,'Spk filter') && size(app.dataChunk{1,1},1)>2
                app.irow = 3; %select filtered data chunck
                app.MinVoltageuVEditField.Value = -150;
                app.MaxVoltageuVEditField.Value = 150;
            else
                app.MinVoltageuVEditField.Value = -500;
                app.MaxVoltageuVEditField.Value = 500;
            end
            % Plot the voltage traces
            plot(app.UIAxes,app.dataChunk{app.iChan,1}(2,:),app.dataChunk{app.iChan,1}(app.irow,:));
            app.UIAxes.XLim = [app.dataChunk{app.iChan,1}(2,1),app.dataChunk{app.iChan,1}(2,end)];
            app.UIAxes.YLim = [app.MinVoltageuVEditField.Value,app.MaxVoltageuVEditField.Value];
            plot(app.UIAxes_2,app.dataChunk{app.iChan,2}(2,:),app.dataChunk{app.iChan,2}(app.irow,:));
            app.UIAxes_2.XLim = [app.dataChunk{app.iChan,2}(2,1),app.dataChunk{app.iChan,2}(2,end)];
            app.UIAxes_2.YLim = [app.MinVoltageuVEditField.Value,app.MaxVoltageuVEditField.Value];
            plot(app.UIAxes_3,app.dataChunk{app.iChan,3}(2,:),app.dataChunk{app.iChan,3}(app.irow,:));
            app.UIAxes_3.XLim = [app.dataChunk{app.iChan,3}(2,1),app.dataChunk{app.iChan,3}(2,end)];
            app.UIAxes_3.YLim = [app.MinVoltageuVEditField.Value,app.MaxVoltageuVEditField.Value];
            % If spk filter is on plot threshold
            if app.irow==3
                hold (app.UIAxes,'on')
                plot(app.UIAxes,xlim(app.UIAxes),[app.FiltdataThr(app.iChan,1) app.FiltdataThr(app.iChan,1)],'r');
                plot(app.UIAxes,xlim(app.UIAxes),[-app.FiltdataThr(app.iChan,1) -app.FiltdataThr(app.iChan,1)],'r');
                hold (app.UIAxes,'off')
                hold (app.UIAxes_2,'on')
                plot(app.UIAxes_2,xlim(app.UIAxes_2),[app.FiltdataThr(app.iChan,2) app.FiltdataThr(app.iChan,2)],'r');
                plot(app.UIAxes_2,xlim(app.UIAxes_2),[-app.FiltdataThr(app.iChan,2) -app.FiltdataThr(app.iChan,2)],'r');
                hold (app.UIAxes_2,'off')
                hold (app.UIAxes_3,'on')
                plot(app.UIAxes_3,xlim(app.UIAxes_3),[app.FiltdataThr(app.iChan,3) app.FiltdataThr(app.iChan,3)],'r');
                plot(app.UIAxes_3,xlim(app.UIAxes_3),[-app.FiltdataThr(app.iChan,3) -app.FiltdataThr(app.iChan,3)],'r');
                hold (app.UIAxes_3,'off')
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Early Segment')
            xlabel(app.UIAxes, 'Time (sec)')
            ylabel(app.UIAxes, 'Voltage (uV)')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Middle Segment')
            xlabel(app.UIAxes_2, 'Time (sec)')
            ylabel(app.UIAxes_2, 'Voltage (uV)')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Late Segment')
            xlabel(app.UIAxes_3, 'Time (sec)')
            ylabel(app.UIAxes_3, 'Voltage (uV)')
            app.LoadReportButton = uibutton(app.UIFigure, 'push');
            app.LoadReportButton.ButtonPushedFcn = createCallbackFcn(app, @LoadReportButtonPushed, true);
            app.LoadReportButton.Text = 'Load Report';
            app.UIAxes_4 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_4, 'Frequency (Hz)')
            ylabel(app.UIAxes_4, 'Power (uW)')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_5, 'Frequency (Hz)')
            ylabel(app.UIAxes_5, 'Power (uW)')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_6, 'Frequency (Hz)')
            ylabel(app.UIAxes_6, 'Power (uW)')
            app.PreviousChannelButton = uibutton(app.UIFigure, 'push');
            app.PreviousChannelButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousChannelButtonPushed, true);
            app.PreviousChannelButton.Text = 'Previous Channel';
            app.NextChannelButton = uibutton(app.UIFigure, 'push');
            app.NextChannelButton.ButtonPushedFcn = createCallbackFcn(app, @NextChannelButtonPushed, true);
            app.NextChannelButton.Text = 'Next Channel';
            app.DataSetLabel = uilabel(app.UIFigure);
            app.DataSetLabel.Text = 'Data Set:';
            app.StandardDeviationLabel = uilabel(app.UIFigure);
            app.StandardDeviationLabel.Text = 'Standard Deviation:';
            app.OpenEphysChannelLabel = uilabel(app.UIFigure);
            app.OpenEphysChannelLabel.Text = 'Open Ephys Channel';
            app.ChNumLabel = uilabel(app.UIFigure);
            app.ChNumLabel.Text = 'ChNum';
            app.ChannelRulingButtonGroup = uibuttongroup(app.UIFigure);
            app.ChannelRulingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChannelRulingButtonGroupSelectionChanged, true);
            app.ChannelRulingButtonGroup.Title = 'Channel Ruling';
            app.AcceptButton = uiradiobutton(app.ChannelRulingButtonGroup);
            app.AcceptButton.Text = 'Accept';
            app.AcceptButton.Value = true;
            app.SilenceButton = uiradiobutton(app.ChannelRulingButtonGroup);
            app.SilenceButton.Text = 'Silence';
            app.QuietButton = uiradiobutton(app.ChannelRulingButtonGroup);
            app.QuietButton.Text = 'Quiet';
            app.MinVoltageuVEditFieldLabel = uilabel(app.UIFigure);
            app.MinVoltageuVEditFieldLabel.Text = 'Min Voltage (uV)';
            app.MinVoltageuVEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinVoltageuVEditField.ValueChangedFcn = createCallbackFcn(app, @MinVoltageuVEditFieldValueChanged, true);
            app.MinVoltageuVEditField.Value = -500;
            app.MaxVoltageuVEditFieldLabel = uilabel(app.UIFigure);
            app.MaxVoltageuVEditFieldLabel.Text = 'Max Voltage (uV)';
            app.MaxVoltageuVEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxVoltageuVEditField.ValueChangedFcn = createCallbackFcn(app, @MaxVoltageuVEditFieldValueChanged, true);
            app.MaxVoltageuVEditField.Value = 500;
            app.MinPoweruWEditFieldLabel = uilabel(app.UIFigure);
            app.MinPoweruWEditFieldLabel.Text = 'Min Power (uW)';
            app.MinPoweruWEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinPoweruWEditField.ValueChangedFcn = createCallbackFcn(app, @MinPoweruWEditFieldValueChanged, true);
            app.MinPoweruWEditField.Value = 0.01;
            app.MaxPoweruWEditFieldLabel = uilabel(app.UIFigure);
            app.MaxPoweruWEditFieldLabel.Text = 'Max Power (uW)';
            app.MaxPoweruWEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxPoweruWEditField.ValueChangedFcn = createCallbackFcn(app, @MaxPoweruWEditFieldValueChanged, true);
            app.MaxPoweruWEditField.Value = 1000;
            app.LoadChannelMapButton = uibutton(app.UIFigure, 'push');
            app.LoadChannelMapButton.ButtonPushedFcn = createCallbackFcn(app, @LoadChannelMapButtonPushed, true);
            app.LoadChannelMapButton.Text = 'Load Channel Map';
            app.ChannelMapFileLabel = uilabel(app.UIFigure);
            app.ChannelMapFileLabel.Text = 'Channel Map File:';
            app.SaveResultsButton = uibutton(app.UIFigure, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = 'Save Results';
            app.OutputFileLabel = uilabel(app.UIFigure);
            app.OutputFileLabel.Text = 'Output File:';
            app.QuickDuplicateButton = uibutton(app.UIFigure, 'push');
            app.QuickDuplicateButton.ButtonPushedFcn = createCallbackFcn(app, @QuickDuplicateButtonPushed, true);
            app.QuickDuplicateButton.Text = 'Quick Duplicate';
            app.DuplicateFileLabel = uilabel(app.UIFigure);
            app.DuplicateFileLabel.Text = 'Duplicate File: ';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Off', 'Spk filter'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = stage1Reviewer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end